classdef ODIN_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel_3                    matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        ProceedtoEditorButton      matlab.ui.control.Button
        CAPCheckBox                matlab.ui.control.CheckBox
        DeltawavesCheckBox         matlab.ui.control.CheckBox
        KcomplexesCheckBox         matlab.ui.control.CheckBox
        SpindlesCheckBox           matlab.ui.control.CheckBox
        AnnotationsLabel           matlab.ui.control.Label
        CheckAnnotationsButton     matlab.ui.control.Button
        Panel_2                    matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        SubjectEditField           matlab.ui.control.EditField
        SubjectEditFieldLabel      matlab.ui.control.Label
        DirectoryEditField         matlab.ui.control.EditField
        DirectoryEditFieldLabel    matlab.ui.control.Label
        FolderEditField            matlab.ui.control.EditField
        FolderEditFieldLabel       matlab.ui.control.Label
        DirectoryEditField_2       matlab.ui.control.EditField
        DirectoryEditField_2Label  matlab.ui.control.Label
        SelectButton               matlab.ui.control.Button
        BrowseButton               matlab.ui.control.Button
        OriginalSignalLabel        matlab.ui.control.Label
        SelectButton_2             matlab.ui.control.Button
        BrowseButton_2             matlab.ui.control.Button
        AnnotationDataLabel        matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        ReferenceDropDown          matlab.ui.control.DropDown
        ReferenceDropDownLabel     matlab.ui.control.Label
        AvailableSignalsListBox    matlab.ui.control.ListBox
        AvailableSignalsLabel      matlab.ui.control.Label
        SelectedSignalsListBox     matlab.ui.control.ListBox
        SelectecSignalsLabel       matlab.ui.control.Label
        ShowSignalsButton          matlab.ui.control.Button
        RemoveButton               matlab.ui.control.Button
        AddButton                  matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        TextArea                   matlab.ui.control.TextArea
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        SelectedDirectory
        SelectedFile
        SelectedDirectoryAnn
        SelectedFileAnn
        FilePath
        FilePathAnn
        Signals
        Header
        Events
        ChannelInfo
        SLFItems
        Annotations struct
        RequiredAnnotationChannels string
        AnnotationFs double
        SortedAnnotations
        OriginalSamplingFrequency
        TargetSamplingFrequency
        FileType
        ProcessedSignals
        ProcessedFs
    end
    
    methods (Access = private)
        
        
        function canceled = checkCancel(app, d)
            drawnow;  % process cancel request
            if d.CancelRequested
                canceled = true;
                uialert(app.UIFigure, 'Loading canceled by user.', 'Canceled');
                close(d);
            else
                canceled = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Icon = 'ODIN_logo.png';
            app.UIFigure.Name = 'ODIN';
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Open directory selection dialog
            selectedDir = uigetdir(pwd, 'Select Directory');
        
            % User pressed Cancel
            if selectedDir == 0
                return;
            end
        
            % Set edit field text to selected directory
            app.SelectedDirectory = selectedDir;
            app.DirectoryEditField.Value = selectedDir;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % Ensure directory is selected
            if isempty(app.SelectedDirectory) || ~isfolder(app.SelectedDirectory)
                uialert(app.UIFigure, ...
                    'Please select a directory first.', ...
                    'No Directory Selected');
                return;
            end

            % --- Safely clear list boxes ---
            app.AvailableSignalsListBox.Items = {};
            app.AvailableSignalsListBox.Value = {};
            
            app.SelectedSignalsListBox.Items = {};
            app.SelectedSignalsListBox.Value = {};
        
            % Get files from directory 
            EDFfiles = dir(fullfile(app.SelectedDirectory, '*.edf'));
            fileNames = {EDFfiles.name};
        
            if isempty(EDFfiles)
                allItems = dir(app.SelectedDirectory);
                % Filter out the folders
                folders = allItems([allItems.isdir]);
                % Remove the '.' and '..' entries
                folders = folders(~ismember({folders.name}, {'.', '..'}));
                % Pick up the file names / ID
                fileNames = {folders.name}';
            end       
            

            if isempty(EDFfiles) && isempty(fileNames)
                uialert(app.UIFigure, ...
                    'No EDF/SLF files found in the selected directory.', ...
                    'No Files Found');
                return;
            end


            % Show selection dialog
            [idx, tf] = listdlg( ...
                'ListString', fileNames, ...
                'SelectionMode', 'single', ...
                'PromptString', 'Select Subject / File', ...
                'ListSize', [300 300]);
        
            if ~tf
                return;
            end
        
            % Store selected file
            app.SelectedFile = fileNames{idx};
            % Store the full file path
            app.FilePath = fullfile(app.SelectedDirectory, app.SelectedFile);
            % Optional: show selection in a text field or label
            app.SubjectEditField.Value = app.SelectedFile;
        end

        % Button pushed function: ShowSignalsButton
        function ShowSignalsButtonPushed(app, event)
            % Valid file selection
            if isempty(app.FilePath)
                uialert(app.UIFigure, ...
                    'Please select a valid file first.', ...
                    'No file selected');
                return;
            end

            if isfolder(app.FilePath)
                fileType = 'slf';  % folder-based SLF
            elseif isfile(app.FilePath)
                [~,~,ext] = fileparts(app.FilePath);
                ext = lower(ext);
                
                switch ext
                    case '.edf'
                        fileType = 'edf';
                    otherwise
                        uialert(app.UIFigure, 'Unsupported file type. Please select EDF or SLF.', 'Error');
                        return;
                end
            else
                uialert(app.UIFigure, 'Path is neither a file nor a folder.', 'Error');
                return;
            end


            if strcmp(fileType, 'edf')
                % Visual feedback
                app.UIFigure.Pointer = 'watch';
                drawnow;
    
                try
                    [header, ~, ~, ch_info] = edf_fileread(app.FilePath, ...
                        'event', ...
                        false, ...
                        {});
                catch ME
                    app.UIFigure.Pointer = 'arrow';
                    uialert(app.UIFigure, ME.message, 'EDF Load Error');
                    return;
                end
    
                % Store
                app.Header = header;
                app.ChannelInfo = ch_info;

                % Ensure labels are column cell array
                labels = ch_info.label(:);

                % Remove "none" if it appears in labels
                labels(strcmpi(labels, 'none')) = [];
    
                % Populate UI
                app.AvailableSignalsListBox.Items = ch_info.label;
                app.ReferenceDropDown.Items = [{'None'}; labels];
                app.AvailableSignalsListBox.Value = {};
                app.SelectedSignalsListBox.Items = {};
                app.FileType = 'edf';
    
                app.UIFigure.Pointer = 'arrow';
            else
                 % Visual feedback
                app.UIFigure.Pointer = 'watch';
                drawnow;

                try
                    % Get a list of all items in the directory
                    items = dir(app.FilePath);
                    items = items([items.isdir]);
                    items = items(~ismember({items.name}, {'.', '..'}));
                catch ME
                    app.UIFigure.Pointer = "arrow";
                    uialert(app.UIFigure, ME.message, 'SLF Load Error');
                    return;
                end

                % Store 
                app.SLFItems = items;

                labels = {items.name};
                labels = labels(:);

                % Populate UI
                app.AvailableSignalsListBox.Items = {items.name};
                app.ReferenceDropDown.Items = [{'None'}; labels];
                app.AvailableSignalsListBox.Value = {};
                app.SelectedSignalsListBox.Items = {};
                app.FileType = 'slf';

                app.UIFigure.Pointer = 'arrow';

            end

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)

            selected = listboxToCell(app.AvailableSignalsListBox.Value);
            if isempty(selected)
                return;
            end

            % Current items
            leftItems  = listboxToCell(app.AvailableSignalsListBox.Items);
            rightItems = listboxToCell(app.SelectedSignalsListBox.Items);

            % Force column cell arrays
            leftItems  = leftItems(:);
            rightItems = rightItems(:);
            selected   = selected(:);

            ref = app.ReferenceDropDown.Value;

            if ~strcmpi(ref, 'None')
                % Build re-referenced signal labels: e.g. C4-M1
                selected = strcat(selected, '-', ref);
            end
        
            % Append selected to rightItems safely
            rightItems = [rightItems; selected];
            rightItems = unique(rightItems, 'stable');   % remove duplicates
        

            % Update list boxes
            app.AvailableSignalsListBox.Items = leftItems;
            app.SelectedSignalsListBox.Items = rightItems;

            % Reset selection
            app.AvailableSignalsListBox.Value = {};
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            
            % ============================
            % Remove from SelectedSignalsListBox
            % ============================
            selectedMain = listboxToCell(app.SelectedSignalsListBox.Value);
            if ~isempty(selectedMain)
        
                rightItems = listboxToCell(app.SelectedSignalsListBox.Items);
        
                rightItems = setdiff(rightItems, selectedMain, 'stable');
        
                app.SelectedSignalsListBox.Items = rightItems;
                app.SelectedSignalsListBox.Value = {};
            end
        

        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            % Open directory selection dialog
            selectedDir = uigetdir(pwd, 'Select Directory');
        
            % User pressed Cancel
            if selectedDir == 0
                return;
            end
        
            % Set edit field text to selected directory
            app.SelectedDirectoryAnn = selectedDir;
            app.DirectoryEditField_2.Value = selectedDir;
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            % Ensure directory is selected
            if isempty(app.SelectedDirectoryAnn) || ~isfolder(app.SelectedDirectoryAnn)
                uialert(app.UIFigure, ...
                    'Please select a directory first.', ...
                    'No Directory Selected');
                return;
            end

            allItems = dir(app.SelectedDirectoryAnn);
            % Filter out the folders
            folders = allItems([allItems.isdir]);
            % Remove the '.' and '..' entries
            folders = folders(~ismember({folders.name}, {'.', '..'}));
            % Pick up the file names / ID
            fileNames = {folders.name}';

            if isempty(fileNames)
                uialert(app.UIFigure, ...
                    'No annotation folders found in the selected directory.', ...
                    'No Files Found');
                return;
            end      
        
            % Show selection dialog
            [idx, tf] = listdlg( ...
                'ListString', fileNames, ...
                'SelectionMode', 'single', ...
                'PromptString', 'Select Subject / File', ...
                'ListSize', [300 300]);
        
            if ~tf
                return;
            end
        
            % Store selected file
            app.SelectedFileAnn = fileNames{idx};
            % Store the full file path
            app.FilePathAnn = fullfile(app.SelectedDirectoryAnn, app.SelectedFileAnn);
            % Optional: show selection in a text field or label
            app.FolderEditField.Value = app.SelectedFileAnn;
        end

        % Button pushed function: CheckAnnotationsButton
        function CheckAnnotationsButtonPushed(app, event)
            % Validate path
            if isempty(app.FilePathAnn)
                uialert(app.UIFigure, ...
                    'Annotation path is invalid or not selected.', ...
                    'Invalid Path');
                return;
            end

            % Read directory contents
            files = dir(app.FilePathAnn);

            % Remove "." and ".."
            files = files(~ismember({files.name}, {'.','..'}));
            fileNames = lower({files.name});

            % Define annotation keywords and corresponding checkboxes
            annotations = struct( ...
                'spindles',    app.SpindlesCheckBox, ...
                'kcomplexes',  app.KcomplexesCheckBox, ...
                'cap',         app.CAPCheckBox, ...
                'delta_waves', app.DeltawavesCheckBox );

            % Check presence and update UI
            fn = fieldnames(annotations);
            for i = 1:numel(fn)
                keyword = fn{i};
                checkbox = annotations.(keyword);

                checkbox.Value = any(contains(fileNames, keyword));
            end
        end

        % Button pushed function: ProceedtoEditorButton
        function ProceedtoEditorButtonPushed(app, event)

            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Preparing Data for Editor', ...
                'Message', 'Initializing...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'on');

            totalSteps = 8;
            step = 0;

            % Check if the correct annotations has been selected
            subjectID = strtrim(app.SubjectEditField.Value);
            subjectTokens = regexp(subjectID, '[A-Za-z0-9]+', 'match');
            [~, annFolderName] = fileparts(app.FilePathAnn);

            tokens = regexp(annFolderName, '[A-Za-z0-9]+', 'match');

            % Check if any subject token matches any annotation-folder token
            matchFound = any( ...
                cellfun(@(s) any(strcmpi(tokens, s)), subjectTokens) ...
            );
            
            if ~matchFound
                uialert(app.UIFigure, ...
                    sprintf([ ...
                        'Subject identifier not found as a distinct token in annotation folder.\n\n' ...
                        'Subject: %s\nAnnotation folder: %s' ], ...
                        subjectID, annFolderName), ...
                    'Subject Mismatch');
                return;
            end
            
            % Validate annotation directory
            if isempty(app.FilePathAnn) || ~isfolder(app.FilePathAnn)
                uialert(app.UIFigure, ...
                    'Annotation directory is not valid.', ...
                    'Invalid Path');
                return;
            end


            % ---------------------------------------------------------
            % 1. Determine selected annotation types
            % ---------------------------------------------------------
            
            step = step + 1;
            d.Value = step / totalSteps;
            d.Message = 'Checking selected annotation types...';
            % Check for cancel
            if checkCancel(app, d)
                return;   % exit gracefully if user canceled
            end
            drawnow;

            annotationMap = struct( ...
                'spindles',    app.SpindlesCheckBox.Value, ...
                'kcomplexes',  app.KcomplexesCheckBox.Value, ...
                'cap',         app.CAPCheckBox.Value, ...
                'delta_waves', app.DeltawavesCheckBox.Value );
        
            selectedTypes = fieldnames(annotationMap);
            selectedTypes = selectedTypes(struct2array(annotationMap));
        
            if isempty(selectedTypes)
                uialert(app.UIFigure, ...
                    'No annotation types selected.', ...
                    'Nothing to Load');
                return;
            end
        
            % ---------------------------------------------------------
            % 2. Find matching annotation files
            % ---------------------------------------------------------

            step = step + 1;
            d.Value = step / totalSteps;
            d.Message = 'Searching annotation files...';
            drawnow;

            files = dir(fullfile(app.FilePathAnn, '*.mat'));
            fileNamesLower = lower({files.name});
            
            availableTypes = {};
            matchedFiles = [];
            
            for i = 1:numel(selectedTypes)
                matches = contains(fileNamesLower, selectedTypes{i});
                if any(matches)
                    availableTypes{end+1} = selectedTypes{i}; 
                    matchedFiles = [matchedFiles; files(matches)]; 
                end
            end
            
            % Remove duplicate files
            [~, ia] = unique({matchedFiles.name}, 'stable');
            matchedFiles = matchedFiles(ia);
            
            % ---------------------------------------------------------
            % 3. Handle missing annotation files
            % ---------------------------------------------------------
            missingTypes = setdiff(selectedTypes, availableTypes);
            
            if ~isempty(missingTypes)
                uialert(app.UIFigure, ...
                    sprintf( ...
                        'The following annotation types were selected but not found:\n\n%s', ...
                        strjoin(missingTypes, ', ') ), ...
                    'Missing Annotation Files');
                return;
            end
            
            % Case 2: Selected but no files matched (defensive)
            if isempty(matchedFiles)
                uialert(app.UIFigure, ...
                    'Selected annotation types were not found in the annotation directory.', ...
                    'Files Not Found');
                return;
            end


            % ---------------------------------------------------------
            % 4. Load annotation data
            % ---------------------------------------------------------

            step = step + 1;
            d.Value = step / totalSteps;
            d.Message = 'Loading annotation files...';
            % Check for cancel
            if checkCancel(app, d)
                return;   % exit gracefully if user canceled
            end
            drawnow;


            annotations = struct();   % container for all loaded annotations
            
            for i = 1:numel(matchedFiles)
            
                filePath = fullfile(app.FilePathAnn, matchedFiles(i).name);
            
                try
                    data = load(filePath);
                catch ME
                    uialert(app.UIFigure, ...
                        sprintf('Failed to load annotation file:\n%s\n\n%s', ...
                        matchedFiles(i).name, ME.message), ...
                        'Annotation Load Error');
                    return;
                end
            
                % Validate contents
                vars = fieldnames(data);
            
                if isempty(vars)
                    uialert(app.UIFigure, ...
                        sprintf('Annotation file %s is empty.', matchedFiles(i).name), ...
                        'Invalid Annotation File');
                    return;
                end
            
                % If file contains exactly one variable, use it directly
                if isscalar(vars)
                    annotations.(vars{1}) = data.(vars{1});
                else
                    % Multiple variables → store under filename-based field
                    [~, baseName] = fileparts(matchedFiles(i).name);
                    annotations.(baseName) = data;
                end
            end


            % ---------------------------------------------------------
            % 5. Extract annotation input channels and check the sampling
            % frequencies
            % ---------------------------------------------------------

            step = step + 1;
            d.Value = step / totalSteps;
            d.Message = 'Validating annotation channels...';
            drawnow;
            
            annFields = fieldnames(annotations);
            requiredChannels = strings(0,1);
            
            for f = 1:numel(annFields)

                tableCell = annotations.(annFields{f});

                for j = 1:length(tableCell)
                        
                    if strcmp(annFields{f}, 'cap')
                        eventCells = tableCell.A_phases;
                    else
                        eventCells = tableCell{2,j};
                    end
    
                    if ~iscell(eventCells) || isempty(eventCells)
                        continue;
                    end
                
                    % Use first event only (channels are consistent per type)
                    evt = eventCells{1};
               
                    if isfield(evt, 'input_channel')
                        chan = {evt.input_channel};
                    elseif isfield(evt, 'channels')
                        chan = {evt.channels};
                        chan = chan(:);
                    else
                        continue;
                    end
    
                    % Append ALL channels
                    requiredChannels = [requiredChannels; chan(:)];
                end
            end
            
            % Normalize & deduplicate
            requiredChannels = unique(requiredChannels);

            app.RequiredAnnotationChannels = requiredChannels;

            % ---------------------------------------------------------
            % Check sampling frequency consistency across annotation types
            % ---------------------------------------------------------
            
            annFields = fieldnames(annotations);
            fsValues  = [];
            fsLabels  = {};
            usedSignalsMap = struct();
            
            for f = 1:numel(annFields)
                
                fieldName = annFields{f};         
                tableCell = annotations.(fieldName);

                for j = 1:length(tableCell)
                    if strcmp(annFields{f}, 'cap')
                        eventCells = tableCell.A_phases;
                    else
                        eventCells = tableCell{2,j};
                    end
                
                    if ~iscell(eventCells) || isempty(eventCells)
                        continue;
                    end
                
                    firstEvent = eventCells{1};
    
                    if strcmp(fieldName, 'cap') && isfield(firstEvent, 'channels')
                        chans = {firstEvent.channels};
                    elseif isfield(firstEvent, 'input_channel')  
                        chans = {firstEvent.input_channel};
                    elseif isfield(firstEvent, 'channels')
                        chans = {firstEvent.channels};
                        
                    else
                        continue;
                    end
    
                    % Normalize to cell array of char
                    if ischar(chans) || isstring(chans)
                        chans = {char(chans)};
                    elseif iscell(chans)
                        chans = chans(:)';
                    else
                        continue;
                    end
                
                    for c = 1:numel(chans)
    
                        sig = matlab.lang.makeValidName(chans{c});
                
                        if ~isfield(usedSignalsMap, sig)
                            usedSignalsMap.(sig) = {fieldName};
                        else
                            if ~ismember(fieldName, usedSignalsMap.(sig))
                                usedSignalsMap.(sig){end+1} = fieldName;
                            end
                        end
                    end
    
                
                    if ~isstruct(firstEvent) || ~isfield(firstEvent, 'sampling_frequency')
                        continue;
                    end
                
                    fsValues(end+1) = firstEvent.sampling_frequency;
                    fsLabels{end+1} = erase(fieldName, 'dataTable');
                end
            end

            if isempty(fsValues)
                uialert(app.UIFigure, ...
                    'No valid sampling frequency information found in annotations.', ...
                    'Annotation Error');
                return;
            end
            
            if numel(unique(fsValues)) > 1
                msg = sprintf( ...
                    'Annotation sampling frequencies do not match:\n\n%s', ...
                    strjoin( ...
                        arrayfun(@(i) ...
                            sprintf('%s: %g Hz', fsLabels{i}, fsValues(i)), ...
                            1:numel(fsValues), ...
                            'UniformOutput', false), ...
                        newline) );
            
                uialert(app.UIFigure, msg, 'Sampling Frequency Mismatch');
                return;
            end

            targetSamplingFrequency = fsValues(1);

            app.TargetSamplingFrequency = targetSamplingFrequency;

            % ---------------------------------------------------------
            % 6. Sort the annotations according to the start time
            % ---------------------------------------------------------

            step = step + 1;
            d.Value = step / totalSteps;
            d.Message = 'Sorting annotations...';
            drawnow;

            allEvents = {};  % Initialize empty array for structs

            annFields = fieldnames(annotations);
            
            for f = 1:numel(annFields)
                
                tableCell = annotations.(annFields{f});

                for j = 1:length(tableCell)
                
                    if strcmp(annFields{f}, 'cap')
                        eventCells = tableCell.A_phases;
                    else
                        eventCells = tableCell{2,j};
                    end
                    
                    if isempty(eventCells) || ~iscell(eventCells)
                        continue;
                    end
                    
                    % Append all events from the first column of eventCells
                    for e = 1:length(eventCells)
                        evt = eventCells{e};
                        if isstruct(evt)
                            allEvents{end+1,1} = evt;  % append to cell array
                        end
                    end
                end
            end
            
            % Sort by start_sec
            startTimes = cellfun(@(x) x.start_sec, allEvents);  % extract start times
            [~, sortIdx] = sort(startTimes);
            allEvents = allEvents(sortIdx);
                     
            % Store in app for later use
            app.SortedAnnotations = allEvents;

            % ---------------------------------------------------------
            % 7. Load, resample and filter the signal according to the
            % selected signals
            % ---------------------------------------------------------

            step = step + 1;
            d.Value = step / totalSteps;
            d.Message = 'Loading signals...';
            % Check for cancel
            if checkCancel(app, d)
                return;   % exit gracefully if user canceled
            end
            drawnow;

            selectedSignals = listboxToCell(app.SelectedSignalsListBox.Items);
            channelsToLoad = {};  % store unique channel names

            if isempty(selectedSignals)
                uialert(app.UIFigure, ...
                    'No signals selected to load.', ...
                    'Load Signals');
                return;
            end
            
            if numel(selectedSignals) > 2 && any(contains(selectedTypes, 'cap'))
                uialert(app.UIFigure, ...
                    'Maximum of two channels is accepted for CAP A-phase observation. It is recommended to select the channels used in analysis.', ...
                    'Too Many Signals');
                return;
            end


            for s = 1:numel(selectedSignals)
                sigName = selectedSignals{s};
                
                if contains(sigName, '-')
                    parts = split(sigName, '-');
                    chan1 = parts{1};
                    chan2 = parts{2};
                    channelsToLoad = [channelsToLoad, chan1, chan2];
                else
                    channelsToLoad = [channelsToLoad, sigName];
                end
            end
            
            channelsToLoad = unique(channelsToLoad);  % only load each channel once
            channelsToLoad = channelsToLoad(:);

            % ---------------------------------------------------------
            % Signal check
            % ---------------------------------------------------------
            
            channelsToLoadNorm = matlab.lang.makeValidName(channelsToLoad);
            usedSignals = fieldnames(usedSignalsMap);
            newUsedSignals = {};
            for i = 1:length(usedSignals)
                parts = split(usedSignals{i}, '_');
                newUsedSignals = [newUsedSignals; parts];   % append
            end
            
            usedSignals = newUsedSignals;
            missingSignals = setdiff(usedSignals, channelsToLoadNorm);

            if ~isempty(missingSignals)

                msgLines = {
                    'Some annotations reference signals are NOT selected for loading.'
                    ''
                    'Missing signals and their associated events:'
                    ''
                };
            
                for i = 1:numel(missingSignals)
                    sig = missingSignals{i};
                    fields = fieldnames(usedSignalsMap);
                    matchIdx = contains(fields, sig);
                    matchedFields = fields(matchIdx);
                    events = usedSignalsMap.(matchedFields{1});

                    % Map internal annotation field names to user-friendly labels
                    eventNameMap = containers.Map( ...
                        {'dataTableSpindle', 'dataTableKcomplex', 'dataTableSWS', 'cap'}, ...
                        {'Spindles',         'K-complexes',       'Delta waves', 'CAP'} );
                    
                    % Replace names where applicable
                    for e = 1:numel(events)
                        if isKey(eventNameMap, events{e})
                            events{e} = eventNameMap(events{e});
                        end
                    end
                    
                    eventStr = strjoin(events, ', ');
            
                    msgLines{end+1} = sprintf('• %s  (events: %s)', sig, eventStr);
                end
            
                msgLines{end+1} = '';
                msgLines{end+1} = ...
                    'It is strongly recommended to load all signals referenced by the annotations to ensure correct visualization in the editor.';
                msgLines{end+1} = '';
                msgLines{end+1} = ...
                    'Do you want to proceed anyway?';
            
                fullMsg = strjoin(msgLines, newline);

                selection = uiconfirm( ...
                    app.UIFigure, ...
                    fullMsg, ...
                    'Proceed Without Required Signals?', ...
                    'Options', {'Proceed', 'Cancel'}, ...
                    'DefaultOption', 2, ...
                    'CancelOption', 2, ...
                    'Icon', 'warning' ...
                );
            
                if strcmp(selection, 'Cancel')
                    return;   % abort loading, stay in current UI
                end
            end
            

            if strcmp(app.FileType, 'slf')
                items = dir(app.FilePath);
                items = items([items.isdir]);                 % keep only folders
                items = items(~ismember({items.name}, {'.', '..'})); % remove '.' and '..'

                loadedRawSignals = struct();  % initialize struct
                
                for c = 1:numel(channelsToLoad)
                    chanName = channelsToLoad{c};

                    % Find folder that matches this channel
                    idx = find(strcmp({items.name}, chanName), 1);
                    if isempty(idx)
                        warning('Channel folder "%s" not found in SLF directory.', chanName);
                        continue;
                    end

                    folderPath = fullfile(app.FilePath, items(idx).name);

                    % Get the list of all .npy files from current dir. There should
                    % be only one .npy file for each channel.
                    npyFile = dir(fullfile(folderPath,'*.npy'));
                    jsonFile = dir(fullfile(folderPath,'*.json'));

                    if length(npyFile) ~= 1
                        uialert(app.UIFigure, ...
                        sprintf('Folder %s does not contain .npy file OR there are more than 1 .npy file.', items(idx).name), ...
                        'Invalid NPY File');
                    return;
                    end

                    if length(jsonFile) ~= 1
                        uialert(app.UIFigure, ...
                        sprintf('Folder %s does not contain .json file OR there are more than 1 .json file.', items(idx).name), ...
                        'Invalid JSON File');
                    return;
                    end

                    % Get the name of .json file for attributes
                    attriName = jsonFile.name;
                    attributeFilePath = fullfile(folderPath, attriName);
                    attributesRead = fileread(attributeFilePath);

                    % Get the attributes
                    attributes = jsondecode(attributesRead);

                    % Store the original sampling frequency
                    app.OriginalSamplingFrequency = attributes.sampling_rate;

                    dataFilePath = fullfile(folderPath, npyFile.name);

                    % Load the .npy file (requires npy-matlab library)
                    NpyData = readNPY(dataFilePath);
                    loadedRawSignals.(chanName) = {NpyData};  

                    % Check for cancel
                    if checkCancel(app, d)
                        return;   % exit gracefully if user canceled
                    end

                end

            else
                % Load signals
                [~, signals, ~, ~] = edf_fileread(app.FilePath, 'signal', false, channelsToLoad);

                loadedRawSignals = struct();

                for c = 1:numel(channelsToLoad)
                    chanName = channelsToLoad{c,1};
                    loadedRawSignals.(chanName) = signals(c, :);   % signals are assumed column-wise
                end
            end
            

            % ---------------------------------------------------------
            % Construct selected signals (with reference subtraction if needed)
            % ---------------------------------------------------------
            loadedSignals = struct();
            for s = 1:numel(selectedSignals)
                sigName = selectedSignals{s};
            
                if contains(sigName, '-')
                    parts = split(sigName, '-');
                    chan1 = parts{1};
                    chan2 = parts{2};
            
                    sigVec = loadedRawSignals.(chan1){1} - loadedRawSignals.(chan2){1};
                else
                    sigVec = loadedRawSignals.(sigName){1};
                end
            
                % Convert field name to safe struct field
                fieldName = strrep(sigName, '-', '_');
                loadedSignals.(fieldName) = sigVec;
            end

            % ---------------------------------------------------------
            % Filter and resample the signals
            % ---------------------------------------------------------

            step = step + 1;
            d.Value = step / totalSteps;
            d.Message = 'Filtering and resampling signals...';
            % Check for cancel
            if checkCancel(app, d)
                return;   % exit gracefully if user canceled
            end
            drawnow;

            % Get the sampling frequencies for resampling
            targetFs = app.TargetSamplingFrequency;

            sigNames = fieldnames(loadedSignals);

            for i = 1:numel(sigNames)
                
                sigName = sigNames{i};
            
                if strcmp(app.FileType, 'slf')
                    % SLF: single global Fs
                    origFs = app.OriginalSamplingFrequency;
            
                else
                    % -------- EDF CASE --------

                    % Get the original sampling frequency
                    ch_info = app.ChannelInfo;
                    labels = ch_info.label(:);
                    fsVec = ch_info.fs(:);
            
                    if contains(sigName, '_')  
                        % Referenced signal (e.g. F4-M1)
                        parts = split(sigName, '_');
                        chan1 = parts{1};
                        chan2 = parts{2};
            
                        idx1 = find(strcmp(labels, chan1), 1);
                        idx2 = find(strcmp(labels, chan2), 1);
            
                        if isempty(idx1) || isempty(idx2)
                            error('EDF channel not found for referenced signal: %s', sigName);
                        end
            
                        fs1 = fsVec(idx1);            
                        origFs = fs1;
            
                    else
                        % Single channel signal
                        idx = find(strcmp(labels, sigName), 1);
            
                        if isempty(idx)
                            error('EDF channel not found: %s', sigName);
                        end
            
                        origFs = fsVec(idx);
                    end
                end
            
            end

            % Design filters
            hpFilt = designfilt('highpassiir', ...
                'FilterOrder', 3, ...
                'PassbandFrequency', 0.1, ...
                'PassbandRipple', 0.001, ...
                'SampleRate', origFs, ...
                'DesignMethod', 'cheby1');
            
            lpFilt = designfilt('lowpassiir', ...
                'FilterOrder', 20, ...
                'PassbandFrequency', 30, ...
                'PassbandRipple', 0.001, ...
                'SampleRate', origFs, ...
                'DesignMethod', 'cheby1');

            % Design filters
            hpFilt_delta = designfilt('highpassiir', ...
                'FilterOrder', 3, ...
                'PassbandFrequency', 0.1, ...
                'PassbandRipple', 0.001, ...
                'SampleRate', origFs, ...
                'DesignMethod', 'cheby1');

            lpFilt_delta = designfilt('lowpassiir', ...
                'FilterOrder', 20, ...
                'PassbandFrequency', 4.5, ...
                'PassbandRipple', 0.001, ...
                'SampleRate', origFs, ...
                'DesignMethod', 'cheby1');

            % Design filters
            hpFilt_sigma = designfilt('highpassiir', ...
                'FilterOrder', 3, ...
                'PassbandFrequency', 11, ...
                'PassbandRipple', 0.001, ...
                'SampleRate', origFs, ...
                'DesignMethod', 'cheby1');

            lpFilt_sigma = designfilt('lowpassiir', ...
                'FilterOrder', 20, ...
                'PassbandFrequency', 16, ...
                'PassbandRipple', 0.001, ...
                'SampleRate', origFs, ...
                'DesignMethod', 'cheby1');


            sigNames = fieldnames(loadedSignals);
            processedSignals = struct();

            nSig = numel(sigNames);
            
            for i = 1:nSig
                sigName = sigNames{i};
                channel = loadedSignals.(sigName);
            
                % Ensure column vector for filtering
                if isrow(channel)
                    channel = channel.';
                end
            
                % Filter + resample
                channel_out = filter_and_resample_EEG( ...
                    channel, ...
                    lpFilt, ...
                    hpFilt, ...
                    targetFs, ...
                    origFs );

                % Store result (preserve naming, e.g. "F4-M1")
                processedSignals.(sigName) = channel_out;

                % Filter + resample
                channel_out_delta = filter_and_resample_EEG( ...
                    channel, ...
                    lpFilt_delta, ...
                    hpFilt_delta, ...
                    targetFs, ...
                    origFs );

                % Store result (preserve naming, e.g. "F4-M1")
                sigNameDelta = sigName + "_delta";
                processedSignals.(sigNameDelta) = channel_out_delta;

                % Filter + resample
                channel_out_sigma = filter_and_resample_EEG( ...
                    channel, ...
                    lpFilt_sigma, ...
                    hpFilt_sigma, ...
                    targetFs, ...
                    origFs );

                % Store result (preserve naming, e.g. "F4-M1")
                sigNameSigma = sigName + "_sigma";
                processedSignals.(sigNameSigma) = channel_out_sigma;


                % Check for cancel
                if checkCancel(app, d)
                    return;   % exit gracefully if user canceled
                end
            end

            step = step + 1;
            d.Value = step / totalSteps;
            % Check for cancel
            if checkCancel(app, d)
                return;   % exit gracefully if user canceled
            end
            drawnow;

            close(d);
            
            % Save to app
            app.ProcessedSignals = processedSignals;
            app.ProcessedFs      = targetFs;


            % ---------------------------------------------------------
            % 8. Launch editor
            % ---------------------------------------------------------
            
            OdinEditor( ...
                app.ProcessedSignals, ...
                app.SortedAnnotations, ...
                app.ProcessedFs, ...
                subjectID);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'ODIN (Observational Dashboard for Integrated Neurodata) is a postprocessing and visualization tool for annotations made by RAVEN. The application allows you to load raw EEG recordings (EDF or SLF format), select and re-reference signals, associate them with precomputed sleep annotations, and prepare all data for synchronized visualization in the ODIN Editor.'; ''; 'Quick start guide:'; ''; '1. To start, select the original signal data by clicking Browse to choose a directory containing EDF files or SLF folders, then click Select to pick a subject or file from the directory and press Show Signals to load the available channels. '; ''; '2. Next, choose one or more signals from Available Signals and optionally select a reference channel to create re-referenced signals (e.g., F4–M1); use Add and Remove to manage the Selected Signals list. '; ''; '3. For annotation data, click Browse to locate the annotation folders, select the folder corresponding to the chosen subject, and click Check Annotations to detect available types. Then, select one or more annotation categories such as Spindles, K-complexes, Delta waves, or CAP; only types included in annotation file will be accepted. '; ''; '4. Finally, click Proceed to Editor to load, filter, and resample signals, which are automatically aligned to the annotation sampling frequency. The ODIN Editor will then open with synchronized signals and annotations ready for review and editing.'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ODIN_logo.jpg');
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add →';
            app.RemoveButton = uibutton(app.GridLayout, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = '← Remove';
            app.ShowSignalsButton = uibutton(app.GridLayout, 'push');
            app.ShowSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSignalsButtonPushed, true);
            app.ShowSignalsButton.Text = 'Show Signals';
            app.SelectecSignalsLabel = uilabel(app.GridLayout);
            app.SelectecSignalsLabel.Text = 'Selected Signals';
            app.SelectedSignalsListBox = uilistbox(app.GridLayout);
            app.SelectedSignalsListBox.Items = {};
            app.SelectedSignalsListBox.Multiselect = 'on';
            app.SelectedSignalsListBox.Value = {};
            app.AvailableSignalsLabel = uilabel(app.GridLayout);
            app.AvailableSignalsLabel.Text = 'Available Signals';
            app.AvailableSignalsListBox = uilistbox(app.GridLayout);
            app.AvailableSignalsListBox.Items = {};
            app.AvailableSignalsListBox.Multiselect = 'on';
            app.AvailableSignalsListBox.Value = {};
            app.ReferenceDropDownLabel = uilabel(app.GridLayout);
            app.ReferenceDropDownLabel.Text = 'Reference';
            app.ReferenceDropDown = uidropdown(app.GridLayout);
            app.ReferenceDropDown.Items = {'None'};
            app.ReferenceDropDown.Value = 'None';
            app.Panel_2 = uipanel(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.AnnotationDataLabel = uilabel(app.GridLayout2);
            app.AnnotationDataLabel.Text = 'Annotation Data';
            app.BrowseButton_2 = uibutton(app.GridLayout2, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.SelectButton_2 = uibutton(app.GridLayout2, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.OriginalSignalLabel = uilabel(app.GridLayout2);
            app.OriginalSignalLabel.Text = 'Original Signal';
            app.BrowseButton = uibutton(app.GridLayout2, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SelectButton = uibutton(app.GridLayout2, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.DirectoryEditField_2Label = uilabel(app.GridLayout2);
            app.DirectoryEditField_2Label.Text = 'Directory';
            app.DirectoryEditField_2 = uieditfield(app.GridLayout2, 'text');
            app.DirectoryEditField_2.Editable = 'off';
            app.FolderEditFieldLabel = uilabel(app.GridLayout2);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.GridLayout2, 'text');
            app.DirectoryEditFieldLabel = uilabel(app.GridLayout2);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.GridLayout2, 'text');
            app.DirectoryEditField.Editable = 'off';
            app.SubjectEditFieldLabel = uilabel(app.GridLayout2);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.GridLayout2, 'text');
            app.Panel_3 = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.Panel_3);
            app.CheckAnnotationsButton = uibutton(app.GridLayout3, 'push');
            app.CheckAnnotationsButton.ButtonPushedFcn = createCallbackFcn(app, @CheckAnnotationsButtonPushed, true);
            app.CheckAnnotationsButton.Text = {'Check'; 'Annotations'};
            app.AnnotationsLabel = uilabel(app.GridLayout3);
            app.AnnotationsLabel.Text = 'Annotations';
            app.SpindlesCheckBox = uicheckbox(app.GridLayout3);
            app.SpindlesCheckBox.Text = 'Spindles';
            app.KcomplexesCheckBox = uicheckbox(app.GridLayout3);
            app.KcomplexesCheckBox.Text = 'K-complexes';
            app.DeltawavesCheckBox = uicheckbox(app.GridLayout3);
            app.DeltawavesCheckBox.Text = 'Delta waves';
            app.CAPCheckBox = uicheckbox(app.GridLayout3);
            app.CAPCheckBox.Text = 'CAP';
            app.ProceedtoEditorButton = uibutton(app.GridLayout3, 'push');
            app.ProceedtoEditorButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoEditorButtonPushed, true);
            app.ProceedtoEditorButton.Text = {'Proceed to'; ' Editor'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ODIN_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end