classdef OdinEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_5                         matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        showhide75VlinesButton          matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        Label_6                         matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        EpochLengthDropDown             matlab.ui.control.DropDown
        EpochLengthDropDownLabel        matlab.ui.control.Label
        SaveExitEditorButton            matlab.ui.control.Button
        GuideButton                     matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        DeleteEventsfromWakeButton      matlab.ui.control.Button
        Label_7                         matlab.ui.control.Label
        HideShowFFTpanelButton          matlab.ui.control.Button
        AddEventButton                  matlab.ui.control.Button
        EditEventButton                 matlab.ui.control.Button
        NextEventButton                 matlab.ui.control.Button
        EditingModeEnabledLabel         matlab.ui.control.Label
        DeleteEventButton               matlab.ui.control.Button
        PreviousEventButton             matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        SelectedEventInfoTextArea       matlab.ui.control.TextArea
        SelectedEventInfoTextAreaLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        N3Label                         matlab.ui.control.Label
        N2Label                         matlab.ui.control.Label
        REMLabel                        matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        N1Label                         matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LoadHypnogramButton             matlab.ui.control.Button
        HypnogramPanel                  matlab.ui.container.Panel
        SignalsPanel                    matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Signals % Description
        SignalsExtra
        Annotations
        SamplingFrequency    
        SignalAxes        
        SignalLines
        CurrentStartSample
        WindowLength
        Events
        CurrentEventIndex
        AnnotationLines
        HypnogramEpochLength
    end
    
    properties (Access = private)
        IsEditMode logical = false
        IsResizing logical = false
        ResizeEdge char
        HasShownEditAlert logical = false
        HasShownAddAlert logical = false
        HypnogramAxes matlab.ui.control.UIAxes
        HypnogramLine matlab.graphics.chart.primitive.Line   % the red vertical line
        TotalDurationSec double
        BackupEvents
        BackupCurrentEventIndex
        SignalNames
        LastNavTic = []
        PreviousEventIndex
        IsAddMode logical = false
        NewEventType string
        IsDrawingNewEvent logical = false
        NewEventStartSec double
        TempNewEventPatch matlab.graphics.primitive.Patch
        TempResizePatch matlab.graphics.primitive.Patch
        ActiveEditSignalIndex = 1
        ActiveEditAxes
        ActiveAddAxes matlab.graphics.axis.Axes = matlab.graphics.axis.Axes.empty
        ActiveAddSignalIndex double = NaN
        ChannelMap
        EventTypes
        ValidEventIndices
        CurrentValidEventPointer
        HelpLines
        NumberOfCapChannels
        TempPairedPatch      % Temporary patch for the paired event (multi-channel)
        PairedEventIndex     % Index of the paired event in app.Events
        Hypnogram
        HasDeleteWakeEvents logical = false;
    end
    
    methods (Access = public)
        
        function createSignalAxes(app)
            % Delete old axes if they exist
            if ~isempty(app.SignalAxes)
                delete(app.SignalAxes)
            end
        
            % sigNames = fieldnames(app.Signals);
            nSignals = numel(app.SignalNames);
        
            panelHeight = app.SignalsPanel.Position(4);
            panelWidth  = app.SignalsPanel.Position(3);
        
        
            % Compute axes height with padding
            padding = 5;
            leftMargin = 50;
            topPadding = 5;        % space above first axes
            bottomMargin = 20;     % space for X-label on last axes
            axHeight = (panelHeight - topPadding - bottomMargin - (nSignals-1)*padding) / nSignals;

            % Preallocate
            app.SignalAxes  = gobjects(nSignals,1);
            app.SignalLines = gobjects(nSignals,1);
            app.AnnotationLines = gobjects(nSignals,1);
        
            fs = app.SamplingFrequency;
            nSamples = min(round(30*fs), length(app.Signals.(app.SignalNames{1})));
            t = (0:nSamples-1)/fs;
        
            % Create axes and plot signals
            for i = 1:nSignals
                ax = uiaxes(app.SignalsPanel);
        
                yBottom = panelHeight - topPadding ...
                    - i*axHeight - (i-1)*padding;
        
                % FIXED plot box
                ax.Position = [leftMargin, yBottom, ...
                    panelWidth - leftMargin - padding, axHeight];

                ax.PositionConstraint = 'innerposition';
                ax.ActivePositionProperty = 'position';
        
                ax.Box = 'on';
                ax.Interactions = [];

                ax.YTickLabel = [];
                ax.YTickMode = 'auto';
        
                if i == nSignals
                    ax.XLabel.String = 'Time (s)';
                else
                    ax.XLabel.String = '';
                    ax.XTickLabel = [];
                end
        
                hold(ax,'on');
        
                sig = app.Signals.(app.SignalNames{i});
        
                % Plot first 30 seconds as a line object for later fast updates
                app.SignalLines(i) = plot(ax, t, sig(1:nSamples), 'LineWidth', 1); 
                ax.YLabel.String = strrep(app.SignalNames{i}, '_', '-');
                ax.YLabel.Units = 'normalized';
                pos = ax.YLabel.Position;
                pos(1) = -0.028;   % adjust to taste
                ax.YLabel.Position = pos;
                app.drawManualYTicks(ax);
                app.SignalAxes(i) = ax;
            end
        end


    end
    
    methods (Access = private)


        % ---------------------------------------------------------
        %  Function for arrowkeys
        % ---------------------------------------------------------
        
        function keyPressNavigation(app, event)

            if ~isempty(app.LastNavTic)
                if toc(app.LastNavTic) < 0.03   
                    return
                end
            end
            app.LastNavTic = tic;

            firstSig = app.SignalNames{1};
            maxStart = max(1, length(app.Signals.(firstSig)) - app.WindowLength + 1);
            step = round(app.WindowLength / 4);   % 25% overlap   
            % Clamp to valid range
            app.CurrentStartSample = max(1, min(app.CurrentStartSample, maxStart));

            switch event.Key
                case 's'
                    if ~any(strcmpi(app.EventTypes, 'spindle'))
                        uialert(app.UIFigure, ...
                            'Event type you are trying to add has not been selected for editor view.', ...
                            'Unacceptable Microstructure', ...
                            'Icon','warning');
                        return
                    end
                    app.Label_7.Text = 'Scoring Spindles';
                    app.NewEventType = 'Spindle';
                    app.Label_7.Visible = "on";
                    
                case 'k'
                    if ~any(strcmpi(app.EventTypes, 'Kcomplex'))
                        uialert(app.UIFigure, ...
                            'Event type you are trying to add has not been selected for editor view.', ...
                            'Unacceptable Microstructure', ...
                            'Icon','warning');
                        return
                    end
                    app.Label_7.Text = 'Scoring K-complexes';
                    app.NewEventType = 'KComplex';
                    app.Label_7.Visible = "on";

                case 'd'
                    if ~any(strcmpi(app.EventTypes, 'Delta_waves'))
                        uialert(app.UIFigure, ...
                            'Event type you are trying to add has not been selected for editor view.', ...
                            'Unacceptable Microstructure', ...
                            'Icon','warning');
                        return
                    end
                    app.Label_7.Text = 'Scoring Delta Waves';
                    app.NewEventType = 'Delta Wave';
                    app.Label_7.Visible = "on";

                case 'space'
                    app.AddEventButtonPushed();

                case 'escape'
                    app.NewEventType = [];
                    app.Label_7.Text = '';
                    app.Label_7.Visible = "off";
                    if app.IsEditMode
                        % ---------------- RESTORE STATE ----------------
                        app.Events = app.BackupEvents;
                        app.CurrentEventIndex = app.BackupCurrentEventIndex;
                        % ------------------------------------------------
        
                        app.IsEditMode = false;
                        app.IsResizing = false;
                        app.EditingModeEnabledLabel.Visible = "off";
                    
                        app.UIFigure.WindowButtonMotionFcn = [];
                        app.UIFigure.WindowButtonUpFcn     = [];
                        app.UIFigure.Pointer = 'arrow';
                    
                        uialert(app.UIFigure, ...
                            'Edit mode cancelled. Changes were reverted.', ...
                            'Edit Cancelled', ...
                            'Icon','warning');
        
                        app.drawAnnotations();
                        app.highlightCurrentEvent();
                        app.updateEventInfoPanel();
                        app.updateSignals();
                        app.EditEventButton.Text = {'Edit'; 'Event'};
                        return
                    end
        
                    if app.IsAddMode && ~app.IsDrawingNewEvent   
                        app.IsAddMode = false;
                        app.UIFigure.Pointer = 'arrow';
                        app.NewEventType = [];
                        app.Label_7.Text = '';
        
                        uialert(app.UIFigure, ...
                            'Adding event cancelled. No events were added.', ...
                            'Adding mode disabled');
                        return;
                    end

                case 'rightarrow'  % move forward one epoch
                    app.CurrentStartSample = app.CurrentStartSample + step; 
                    
                case 'leftarrow'   % move backward one epoch
                    app.CurrentStartSample = app.CurrentStartSample - step;

                case 'uparrow' % move forward one event
                    idx = app.CurrentEventIndex + 1;
                    while idx <= numel(app.Events) && app.Events(idx).deleted
                        idx = idx + 1;
                    end
                    if idx <= numel(app.Events)
                        app.selectEvent(idx);
                    end
                    app.updateFFT();

                case 'downarrow' % move backward one event
                    idx = app.CurrentEventIndex - 1;
                    while idx >= 1 && app.Events(idx).deleted
                        idx = idx - 1;
                    end
                    if idx >= 1
                        app.selectEvent(idx);
                    end
                    app.updateFFT();

                case 'delete' % delete current event
                    DeleteEventButtonPushed(app, event);
            end


            app.updateSignals();
            
        end


        % ---------------------------------------------------------
        %  Create the editable event list
        % ---------------------------------------------------------
        
        function events = flattenAnnotations(~, annotations)

            events = struct([]);
            k = 0;
        
            for i = 1:numel(annotations)
        
                evt = annotations{i};
        
                % Required fields
                if ~isfield(evt,'start_sec') || ~isfield(evt,'duration')
                    continue
                end
        
                start_sec = evt.start_sec;
                duration  = evt.duration;
        
                % Defensive checks
                if ~isscalar(start_sec) || ~isscalar(duration) || duration <= 0
                    continue
                end
        
                k = k + 1;
                
                events(k).File_ID = evt.File_ID;
                events(k).start_sec = start_sec;
                events(k).end_sec   = start_sec + duration;
                events(k).duration  = duration;
        
                % Optional metadata (safe guards)
                events(k).type     = evt.event_name;
                if strcmp(events(k).type, 'A_phase')
                    events(k).channels = {evt.channels};
                elseif isfield(evt, 'input_channel')
                    events(k).channels = evt.input_channel;
                elseif isfield(evt, 'channels')
                    events(k).channels = evt.channels;
                end

                events(k).sampling_frequency = evt.sampling_frequency;
                events(k).indices = evt.indices;

                if strcmp(events(k).type, 'spindle')
                    events(k).amplitude = evt.amplitude;
                    events(k).spindle_power = evt.spindle_power;
                    if isfield(evt, 'max_freq_fft')
                        events(k).max_fft = evt.max_freq_fft;
                    else
                        events(k).max_fft = evt.max_fft;
                    end
                end

                if strcmp(events(k).type, 'Kcomplex')
                    events(k).amplitude = evt.amplitude;
                    events(k).slope_width_seconds = evt.slope_width_seconds;
                end

                if strcmp(events(k).type, 'Delta_waves')
                    events(k).delta_power = evt.delta_power;
                    events(k).max_fft = evt.max_fft;
                end
                events(k).add_name = evt.name;
                
        
                % Editor bookkeeping
                events(k).deleted  = false;
                events(k).edited   = false;
            end
        
            % Sort by start time
            if ~isempty(events)
                [~,idx] = sort([events.start_sec]);
                events = events(idx);
            end


            % ---- Split multi-channel A_phase events ----
            i = 1;
            
            while i <= numel(events)
            
                if strcmp(events(i).type, 'A_phase') && ...
                        iscell(events(i).channels) && ...
                        numel(events(i).channels) > 1
            
                    chList = events(i).channels;
            
                    % Keep first channel in the original event
                    events(i).channels = chList{1};
            
                    % Duplicate for remaining channels
                    for ch = 2:numel(chList)
                        newEvt = events(i);
                        newEvt.channels = chList{ch};
            
                        % Insert after current event
                        events = [events(1:i), newEvt, events(i+1:end)];
                        i = i + 1;
                    end
                end
            
                i = i + 1;
            end

            all_channels = {events.channels};
            unique_channels = unique(all_channels);

            if isscalar(unique_channels)

                % Create matrix of start and end indices
                idxStart = arrayfun(@(e) e.indices(1), events)';
                idxEnd   = arrayfun(@(e) e.indices(end), events)';
            
                % Detect unique rows
                [~, uniqueIdx] = unique([idxStart idxEnd], 'rows');
            
                % Keep only unique events
                events = events(uniqueIdx);
            
            end

        end

        % ---------------------------------------------------------
        %  Function for drawing the annotation
        % ---------------------------------------------------------
        
        function drawAnnotations(app)

            if isempty(app.Events)
                return
            end
        
            app.initializeAnnotationPatches();
        
            fs = app.SamplingFrequency;
            winStartSec = (app.CurrentStartSample - 1) / fs;
            winEndSec   = winStartSec + app.WindowLength / fs;

            channelMap = app.ChannelMap;

            nEvents  = numel(app.Events);
        
            for e = 1:nEvents
        
                evt = app.Events(e);

                if ~isKey(channelMap, evt.channels)
                    continue   % skip if channel not loaded
                end

                p = app.AnnotationLines(e);
        
                % Deleted events → hide
                if evt.deleted
                    if isgraphics(p)
                        p.Visible = 'off';
                    end
                    continue
                end
        
                % Clip to window
                clipStart = max(evt.start_sec, winStartSec);
                clipEnd   = min(evt.end_sec,   winEndSec);
        
                if clipEnd <= clipStart
                    % Outside window → hide
                    if isgraphics(p)
                        p.Visible = 'off';
                    end
                    continue
                end
        
                % Update visible patch
                x1 = clipStart - winStartSec;
                x2 = clipEnd   - winStartSec;


                % Get correct axis from channel
                s = channelMap(evt.channels);
                % ax = app.SignalAxes(s);

                ydata = app.SignalLines(s).YData;
                ymin = min(ydata);
                ymax = max(ydata);
                % y  = ylim(ax);

                if isgraphics(p)
                    p.XData   = [x1 x2 x2 x1];
                    p.YData   = [ymin ymin ymax ymax];
                    p.Visible = 'on';
                end

            end
        end


        % ---------------------------------------------------------
        %  Funtion for event selection
        % ---------------------------------------------------------
        
        function selectEvent(app, eventIndex)

            app.CurrentEventIndex = eventIndex;
        
            % Move epoch so event is centered
            fs = app.SamplingFrequency;
            centerSec = mean([ ...
                app.Events(eventIndex).start_sec, ...
                app.Events(eventIndex).end_sec ]);
        
            centerSample = round(centerSec * fs);

            startSample = centerSample - round(app.WindowLength / 2);

            firstSig = fieldnames(app.Signals);
            sigLen = length(app.Signals.(firstSig{1}));
            startSample = max(1, min(startSample, sigLen - app.WindowLength + 1));

            app.CurrentStartSample = startSample;
        
            app.updateSignals();
            app.highlightCurrentEvent();
            app.updateEventInfoPanel();
        end


        % ---------------------------------------------------------
        %  Function for event highlighting
        % ---------------------------------------------------------
        
        function highlightCurrentEvent(app)

            if isempty(app.CurrentEventIndex) || ...
               app.CurrentEventIndex < 1 || ...
               app.CurrentEventIndex > numel(app.Events)
                return
            end
        
            % Un-highlight previous
            if ~isempty(app.PreviousEventIndex)
                e = app.PreviousEventIndex;
                for s = 1:size(app.AnnotationLines,1)
                    p = app.AnnotationLines(s,e);
                    if isgraphics(p)
                        p.FaceColor = 'r';
                        p.FaceAlpha = 0.15;
                    end
                end
            end
        
            % Highlight current
            e = app.CurrentEventIndex;
            for s = 1:size(app.AnnotationLines,1)
                p = app.AnnotationLines(s,e);
                if isgraphics(p) && strcmp(p.Visible,'on')
                    p.FaceColor = 'y';
                    p.FaceAlpha = 0.3;
                end
            end
        
            app.PreviousEventIndex = e;
        end


        % ---------------------------------------------------------
        %  Update signals
        % ---------------------------------------------------------


        function updateSignals(app)

            fs = app.SamplingFrequency;
        
            for i = 1:numel(app.SignalNames)
        
                sig = app.Signals.(app.SignalNames{i});
        
                startIdx = app.CurrentStartSample;
                endIdx = min(startIdx + app.WindowLength - 1, numel(sig));
        
                startIdx = max(1, endIdx - app.WindowLength + 1);
        
                t = (0:(endIdx-startIdx))/fs;

                app.SignalLines(i).XData = t; 
                app.SignalLines(i).YData = sig(startIdx:endIdx);
                
            end

            for i = 1:numel(app.SignalAxes)
                ax = app.SignalAxes(i);

                if isempty(ax.YLabel.String)
                    ax.YLabel.String = strrep(app.SignalNames{i}, '_', '-');
                end
            
                app.drawManualYTicks(ax);
            end

            app.drawAnnotations();
            app.highlightCurrentEvent();
            app.updateHypnogramIndicator();
        
            drawnow limitrate nocallbacks
        end


        % ---------------------------------------------------------
        %  Update signals info panel
        % ---------------------------------------------------------
        
        function updateEventInfoPanel(app)

            if isempty(app.CurrentEventIndex) || ...
               app.CurrentEventIndex < 1 || ...
               app.CurrentEventIndex > numel(app.Events)
                return
            end
        
            e = app.Events(app.CurrentEventIndex);
        
            % Build formatted text (one line per cell)
            infoText = {
                sprintf('Event #%d', app.CurrentEventIndex)
                ''
                sprintf('Type: %s', string(e.type))
                sprintf('Channel: %s\n', string(e.channels))
                ''
                sprintf('Start: %.3f s', e.start_sec)
                sprintf('End: %.3f s', e.end_sec)
                sprintf('Duration: %.3f s', e.duration)
            };
        
            app.SelectedEventInfoTextArea.Value = infoText;
        end


        % ---------------------------------------------------------
        %  Select Annotations by clicking
        % ---------------------------------------------------------
        
        function onAnnotationClicked(app, eventIndex, sigIndex)

            app.ActiveEditSignalIndex = sigIndex;
            app.ActiveEditAxes = app.SignalAxes(sigIndex);

            % --- Sync valid event pointer ---
            pos = find(app.ValidEventIndices == eventIndex, 1);
            if ~isempty(pos)
                app.CurrentValidEventPointer = pos;
            end

            % If not editing → just select
            if ~app.IsEditMode
                app.selectEvent(eventIndex);
                app.updateFFT();
                return
            end
        
            % Editing → start resize using index only
            app.startResizeEvent(eventIndex);
        end


        % ---------------------------------------------------------
        %  Resize in edit mode
        % ---------------------------------------------------------
        
        function startResizeEvent(app, eventIndex)

            if ~app.IsEditMode
                return
            end
        
            app.CurrentEventIndex = eventIndex;
        
            ax = app.ActiveEditAxes;
            if isempty(ax) || ~isgraphics(ax)
                return
            end

            cp = ax.CurrentPoint;
            if isempty(cp)
                return
            end
            xClick = cp(1,1);
        
            fs = app.SamplingFrequency;
            winStartSec = (app.CurrentStartSample - 1) / fs;
        
            e = app.Events(eventIndex);
            x1 = e.start_sec - winStartSec;
            x2 = e.end_sec   - winStartSec;
        
            % Decide which edge is grabbed
            if abs(xClick - x1) < abs(xClick - x2)
                app.ResizeEdge = 'left';
            else
                app.ResizeEdge = 'right';
            end
        
            app.IsResizing = true;
        
            % Create a temporary patch for fast redraw
            y = ylim(ax);
            app.TempResizePatch = patch(ax, [x1 x2 x2 x1], [y(1) y(1) y(2) y(2)], ...
                'g', 'FaceAlpha', 0.25, 'EdgeColor', 'none');

            if numel(fieldnames(app.Signals)) == 2 && strcmp(e.type,'A_phase')
                if app.NumberOfCapChannels > 1 
                    tol = 1e-6;
                    % Look for the paired event
                    if eventIndex > 1 && abs(app.Events(eventIndex-1).start_sec - e.start_sec) < tol
                        app.PairedEventIndex = eventIndex - 1;
                    elseif eventIndex < numel(app.Events) && abs(app.Events(eventIndex+1).start_sec - e.start_sec) < tol
                        app.PairedEventIndex = eventIndex + 1;
                    end
                end
            
                % If a paired event exists, create its temp patch using the same logic
                if ~isempty(app.PairedEventIndex)
                    pairedIdx = app.PairedEventIndex;
                    pairedEvent = app.Events(pairedIdx);
                    pairedAx = app.SignalAxes(app.ChannelMap(pairedEvent.channels));
            
                    % Compute initial coordinates
                    x1_p = pairedEvent.start_sec - winStartSec;
                    x2_p = pairedEvent.end_sec - winStartSec;
                    yPaired = ylim(pairedAx);
            
                    % Create temporary patch for paired event
                    app.TempPairedPatch = patch(pairedAx, [x1_p x2_p x2_p x1_p], ...
                        [yPaired(1) yPaired(1) yPaired(2) yPaired(2)], ...
                        'g', 'FaceAlpha', 0.25, 'EdgeColor', 'none');
                end
            end


            app.UIFigure.WindowButtonMotionFcn = @(~,~)app.updateResizePatch();
            app.UIFigure.WindowButtonUpFcn     = @(~,~)app.finishResizeEvent();
        end


        % ---------------------------------------------------------
        %  Cursor shape change
        % ---------------------------------------------------------
        
        function updateCursor(app)

            if ~app.IsEditMode
                app.UIFigure.Pointer = 'arrow';
                return
            end

            obj = hittest(app.UIFigure);

            ax = ancestor(obj, 'matlab.graphics.axis.Axes');

            % If mouse is not over any signal axes → arrow
            if isempty(ax) || ~ismember(ax, app.SignalAxes) || ~isgraphics(ax)
                app.UIFigure.Pointer = 'arrow';
                return
            end

            cp = get(ax, 'CurrentPoint');
            if isempty(cp)
                app.UIFigure.Pointer = 'arrow';
                return
            end

            x = cp(1,1);

            fs = app.SamplingFrequency;
            winStartSec = (app.CurrentStartSample - 1) / fs;

            e = app.Events(app.CurrentEventIndex);
            x1 = e.start_sec - winStartSec;
            x2 = e.end_sec   - winStartSec;

            tol = 0.05; % seconds tolerance near edge

            if abs(x - x1) < tol || abs(x - x2) < tol
                app.UIFigure.Pointer = 'left';
            else
                app.UIFigure.Pointer = 'arrow';
            end
        end


        % ---------------------------------------------------------
        %  Create Hypnogram
        % ---------------------------------------------------------
        
        function createHypnogramAxes(app)

            % Create axes inside HypnogramPanel
            ax = uiaxes(app.HypnogramPanel);
            ax.Position = [10 10 app.HypnogramPanel.Position(3)-20 ...
                                  app.HypnogramPanel.Position(4)-30];
        
            ax.YTick = [];
            ax.YLabel.String = '';
            ax.Box = 'on';
            ax.Interactions = [];

        
            % Total duration (seconds)
            % sigNames = fieldnames(app.Signals);
            nSamples = length(app.Signals.(app.SignalNames{1}));
            app.TotalDurationSec = nSamples / app.SamplingFrequency;
        
            % X-axis spans full night
            totalHours = app.TotalDurationSec / 3600;

            ax.XLim = [0 totalHours];
            ax.XLabel.String = 'Time (h)';

            ax.YLim = [0 1];
        
            hold(ax,'on');
        
            % Red vertical indicator
            app.HypnogramLine = plot(ax, [0 0], ax.YLim, ...
                'r', 'LineWidth', 2);
        
            % Click-to-navigate
            ax.ButtonDownFcn = @(~,~)app.onHypnogramClicked();
        
            app.HypnogramAxes = ax;
        end

        % ---------------------------------------------------------
        %  Update hypnogrma indicator
        % ---------------------------------------------------------
        
        function updateHypnogramIndicator(app)

            if isempty(app.HypnogramAxes) || isempty(app.HypnogramLine)
                return
            end

            % Recreate cursor if missing or deleted
            if isempty(app.HypnogramLine) || ~isgraphics(app.HypnogramLine)
                app.HypnogramLine = plot( ...
                    app.HypnogramAxes, ...
                    [0 0], ...
                    app.HypnogramAxes.YLim, ...
                    'r', 'LineWidth', 2, ...
                    'HitTest','off');
            end

        
            fs = app.SamplingFrequency;
            tSec = (app.CurrentStartSample - 1) / fs;
            tHours = tSec / 3600;
        
            app.HypnogramLine.XData = [tHours tHours];
            app.HypnogramLine.YData = app.HypnogramAxes.YLim;
        end


        % ---------------------------------------------------------
        %  Move by clicking the hypnogram
        % ---------------------------------------------------------
        
        function onHypnogramClicked(app)

            cp = app.HypnogramAxes.CurrentPoint;
            tHours = cp(1,1);
            tSec   = tHours * 3600;
        
            fs = app.SamplingFrequency;
            newStartSample = round(tSec * fs) + 1;
        
            % Clamp
            % sigNames = fieldnames(app.Signals);
            sigLen = length(app.Signals.(app.SignalNames{1}));
            maxStart = max(1, sigLen - app.WindowLength + 1);
        
            app.CurrentStartSample = max(1, min(newStartSample, maxStart));
        
            app.updateSignals();
        end

        % ---------------------------------------------------------
        %  Load the hypnogram
        % ---------------------------------------------------------
        
        function openHypnogramLoadDialog(app)

            d = uifigure( ...
                'Name','Load Hypnogram', ...
                'WindowStyle','modal', ...
                'Position',[600 400 420 360]);
        
            % -------- File type selection --------
            uilabel(d, 'Text','File type:', ...
                'Position',[20 320 100 22], 'FontWeight','bold');
            
            bg = uibuttongroup(d, 'Position',[20 270 200 50]);
            rbXML = uiradiobutton(bg,'Text','XML','Position',[10 10 60 22]);
            rbTXT = uiradiobutton(bg,'Text','TXT / CSV','Position',[80 10 100 22]);
            bg.SelectedObject = rbXML;
            
            % -------- File browser --------
            uilabel(d,'Text','File:', ...
                'Position',[20 230 50 22],'FontWeight','bold');
            
            fileEdit = uieditfield(d,'text', ...
                'Position',[70 230 250 22]);
            
            uibutton(d,'Text','Browse...', ...
                'Position',[330 230 70 22], ...
                'ButtonPushedFcn',@browseFile);
            
            % -------- TXT options --------
            txtPanel = uipanel(d, ...
                'Title','TXT / CSV Options', ...
                'Position',[20 90 320 120], ... 
                'Visible','off');
            
            % Grid layout for txt options
            gl = uigridlayout(txtPanel, ...
                'RowHeight',{'fit','fit','fit'}, ...
                'ColumnWidth',{110, 30, 100}, ...
                'Padding',[10 10 10 10], ...
                'RowSpacing',8, ...
                'ColumnSpacing',10);
            
            % ---------------- Row 1: Separator ----------------
            lblSep = uilabel(gl, 'Text', 'Separator:');
            lblSep.Layout.Row = 1;
            lblSep.Layout.Column = 1;
            
            sepEdit = uieditfield(gl, 'text', 'Value', ',', ...
                'HorizontalAlignment', 'center');
            sepEdit.Layout.Row = 1;
            sepEdit.Layout.Column = 2;   % edit field column
            
            lblSepInfo = uilabel(gl, 'Text', '(",", "tab", "space")');
            lblSepInfo.Layout.Row = 1;
            lblSepInfo.Layout.Column = 3;
            
            % ---------------- Row 2: Hypnogram column ----------------
            lblCol = uilabel(gl, 'Text', 'Hypnogram column:');
            lblCol.Layout.Row = 2;
            lblCol.Layout.Column = 1;
            
            colEdit = uieditfield(gl, 'numeric', 'Value', 1, ...
                'HorizontalAlignment', 'center');
            colEdit.Layout.Row = 2;
            colEdit.Layout.Column = 2;   % same column as sepEdit
            
            % ---------------- Row 3: Data starts at line ----------------
            lblLine = uilabel(gl, 'Text', 'Data starts at line:');
            lblLine.Layout.Row = 3;
            lblLine.Layout.Column = 1;
            
            lineEdit = uieditfield(gl, 'numeric', 'Value', 1, ...
                'HorizontalAlignment', 'center');
            lineEdit.Layout.Row = 3;
            lineEdit.Layout.Column = 2;  % same column as sepEdit
                        
            % -------- Buttons --------
            uibutton(d,'Text','Load', ...
                'Position',[220 20 80 30], ...
                'ButtonPushedFcn',@loadHypnogram);
            
            uibutton(d,'Text','Cancel', ...
                'Position',[310 20 80 30], ...
                'ButtonPushedFcn',@(~,~)delete(d));
            
            % -------- Callback to show TXT options --------
            bg.SelectionChangedFcn = @(~,e) ...
                set(txtPanel,'Visible',strcmp(e.NewValue.Text,'TXT / CSV'));
        
            % -------- Nested functions --------
            function browseFile(~,~)
                if strcmp(bg.SelectedObject.Text,'XML')
                    [f,p] = uigetfile('*.xml','Select hypnogram XML');
                else
                    [f,p] = uigetfile({'*.txt;*.csv'},'Select hypnogram TXT/CSV');
                end
                if f ~= 0
                    fileEdit.Value = fullfile(p,f);
                end
            end


            function loadHypnogram(~,~)
                file = fileEdit.Value;
                if isempty(file) || ~isfile(file)
                    uialert(d,'Please select a valid file.','File error');
                    return
                end
        
                try
                    if strcmp(bg.SelectedObject.Text,'XML')
                        hyp = app.loadHypnogramXML(file);
                        app.Hypnogram = hyp;
                    else
                        hyp = app.loadHypnogramTXT( ...
                            file, sepEdit.Value, colEdit.Value, lineEdit.Value);
                        app.Hypnogram = hyp;
                    end
                catch ME
                    uialert(d,ME.message,'Load error');
                    return
                end
        
                delete(d);
                app.plotHypnogram(hyp);
            end
        end
        
        function hyp = loadHypnogramTXT(~, file, sep, col, startLine)

            % Convert user-friendly separator to actual character
            switch sep
                case 'tab'
                    sepChar = sprintf('\t');
                case 'space'
                    sepChar = ' ';
                otherwise
                    sepChar = sep;  % e.g., ','
            end

            opts = detectImportOptions(file,'Delimiter',sepChar);
            opts.DataLines = [startLine inf];
        
            T = readtable(file,opts);
        
            if col > width(T)
                error('Selected hypnogram column does not exist.');
            end
        
            hyp = T{:,col};
            hyp = normalizeHypnogram(hyp);  % hyp can be your 1677×1 cell array
        end


        function hyp = loadHypnogramXML(~, file)

            S = readstruct(file);
        
            % Access SleepStages
            stages = S.SLEEPSTAGES.SLEEPSTAGE(:);  % adjust to your XML structure
            hyp = stages(:);

            hyp = normalizeHypnogram(hyp);
        
        end


        function plotHypnogram(app, hyp)

            ax = app.HypnogramAxes;
            hold(ax,'on');

            delete(findall(ax,'Type','patch'))   % delete only hypnogram blocks
        
            % Ensure white background (non-destructive)
            ax.Color = 'w';
        
            % Epoch duration (seconds → hours)
            epochDurSec = app.HypnogramEpochLength / app.SamplingFrequency;
            epochDurHr  = epochDurSec / 3600;
        
            n = numel(hyp);
        
            % Color map
            stageColors = containers.Map( ...
                {0, 1, 2, 3, 4}, ...
                { ...
                    [166 206 227]/255, ...
                    [51 160 44]/255, ...
                    [253 191 111]/255, ...
                    [177 89 40]/255, ...
                    [106 61 154]/255 ...
                });

            % Show the color coding
            app.Panel.Visible = "on";
            app.Label.Visible = "on";
            app.Label.BackgroundColor = [166 206 227]/255;
            app.Label_2.Visible = "on";
            app.Label_2.BackgroundColor = [51 160 44]/255;
            app.Label_3.Visible = "on";
            app.Label_3.BackgroundColor = [253 191 111]/255;
            app.Label_5.Visible = "on";
            app.Label_5.BackgroundColor = [177 89 40]/255;
            app.Label_4.Visible = "on";
            app.Label_4.BackgroundColor = [106 61 154]/255;

            app.WLabel.Visible = "on";
            app.N1Label.Visible = "on";
            app.N2Label.Visible = "on";
            app.N3Label.Visible = "on";
            app.REMLabel.Visible = "on";
        
            % Fixed vertical extent (do NOT change axes limits)
            y0 = 0;
            y1 = 1;
        
            % Draw each epoch as a colored block
            for i = 1:n
                stage = hyp(i);
        
                if ~isKey(stageColors, stage)
                    continue
                end
        
                t0 = (i-1) * epochDurHr;
                t1 = i * epochDurHr;
        
                patch(ax, ...
                    [t0 t1 t1 t0], ...
                    [y0 y0 y1 y1], ...
                    stageColors(stage), ...
                    'EdgeColor','none', ...
                    'HitTest','off');
            end
        
            hold(ax,'off');
            uistack(app.HypnogramLine, 'top');
            app.updateHypnogramIndicator();
            
        end


        function evtOut = rebuildEventStruct(~, evtIn)
            % Start empty
            evtOut = struct();
        
            % Rename required fields
            evtOut.start_sec = evtIn.start_sec;
            evtOut.duration  = evtIn.duration;
            evtOut.end_sec   = evtIn.end_sec;
            evtOut.event_name = evtIn.type;
            evtOut.name      = evtIn.add_name;
            evtOut.File_ID   = evtIn.File_ID;
            evtOut.sampling_frequency = evtIn.sampling_frequency;
            evtOut.indices   = evtIn.indices;
        
            % -------------------------------------------------
            % Copy ALL other fields that contain useful data
            % -------------------------------------------------
            f = fieldnames(evtIn);
        
            exclude = ["start_sec","duration","end_sec","type", ...
                       "add_name","File_ID","sampling_frequency", ...
                       "indices","deleted","edited"];
        
            f = setdiff(f, exclude);
        
            for k = 1:numel(f)
        
                val = evtIn.(f{k});
        
                if isnumeric(val)
                    % Keep if not empty and not all NaN
                    if ~isempty(val) && ~all(isnan(val(:)))
                        evtOut.(f{k}) = val;
                    end
                else
                    % Keep if not empty
                    if ~isempty(val)
                        evtOut.(f{k}) = val;
                    end
                end
            end
        end
        
        function showQuickStartDialog(app)
            % Create modal figure
            d = uifigure('Name', 'ODIN Quick Start', ...
                         'WindowStyle', 'modal', ...
                         'Position', [600 400 500 500]);
            
            % Quick Start text
            guideText = {...
                '1. Navigation & Events', ...
                '', ...
                '• Use Next/Previous Event buttons or Up/Down arrow keys to move between events.', ...
                '• Use Left/Right arrow keys to scroll the signals in the signal panel.', ...
                '• Click on the hypnogram panel to jump to a specific point in the recording.', ...
                '• Click an event in the signal panel to select it. Event-specific details are shown on the Selected Event panel and FFT panel.', ...
                '• Delete Event removes the current event (confirmation required). You can also use delete button from keyboard.', ...
                '• Delete Events from Wake removes events that start during wake epoch (hypnogram required).', ...
                '', ...
                '2. Edit & Add Mode', ...
                '', ...
                '• Click Edit Event to resize the selected event by dragging its edges.', ...
                '• Press Edit Event again to finish editing, or ESC to cancel.', ...
                '• Edited events are tracked separately and saved when exiting.', ...
                '• Use keyboard to select event type to add.', ...
                '• Press spacebar or Add Event button to add event on the signal.', ...
                '• Added events are tracked separately and saved when exiting.', ...
                '• When adding an event, ODIN editor will process the event as RAVEN does.', ...
                'You will have the same information per event as it would be scored by RAVEN.', ...
                '', ...
                '3. Signals Panel', ...
                '', ...
                '• Displays loaded EEG or physiological signals.', ...
                '• Adjust the epoch length using the Epoch Length dropdown.', ...
                '• Signals scroll automatically when navigating events.', ...
                '', ...
                '4. Hypnogram Panel', ...
                '', ...
                '• Shows sleep stages for the entire recording.', ...
                '• The red line indicates the current signal window.', ...
                '• Click on the hypnogram to jump to a specific time.', ...
                '• Load external hypnograms via Load Hypnogram (XML or TXT/CSV).', ...
                '', ...
                '5. Saving', ...
                '', ...
                '• Click Save & Exit Editor to save all edited events.', ...
                '• Events are saved by type in MAT files; deleted events are ignored.', ...
                '• Save format is same as in RAVEN. If you open the saved file and save it again in the same folder, the old file will be replaced.', ...
                '• Filenames follow the format: "SubjectID_EventType_Edited".'};
                
            % Text area with scroll
            uitextarea(d, ...
                             'Position', [20 20 460 460], ...
                             'Editable', 'off', ...
                             'FontName', 'Century Gothic', ...
                             'FontSize', 16, ...
                             'Value', guideText);
        end
                             
        
        function initializeAnnotationPatches(app)

            % nSignals = numel(app.SignalNames);
            nEvents  = numel(app.Events);
        
            % Always rebuild if empty OR size mismatch
            if isempty(app.AnnotationLines) || ...
               size(app.AnnotationLines,2) ~= nEvents
        
                % delete old patches safely
                if ~isempty(app.AnnotationLines)
                    delete(app.AnnotationLines(isgraphics(app.AnnotationLines)))
                end
        
                app.AnnotationLines = gobjects(1, nEvents);

                % Precompute channel indices
                channelMap = app.ChannelMap;
                channelIndices = zeros(1,nEvents);
                for e = 1:nEvents
                    if isKey(channelMap, app.Events(e).channels)
                        channelIndices(e) = channelMap(app.Events(e).channels);
                    end
                end
        
                % Precompute y-limits for all axes
                axYLim = containers.Map('KeyType','double','ValueType','any');
                for s = 1:numel(app.SignalAxes)
                    axYLim(s) = ylim(app.SignalAxes(s));
                end
        
                % Create patches
                for e = 1:nEvents
                    s = channelIndices(e);
                    if s == 0
                        continue
                    end
        
                    ax = app.SignalAxes(s);
                    y  = axYLim(s);
        
                    p = patch(ax, [0 0 0 0], [y(1) y(1) y(2) y(2)], ...
                              'r', 'FaceAlpha', 0.15, 'EdgeColor', 'none', 'Visible', 'off');
        
                    p.ButtonDownFcn = @(~,~) app.onAnnotationClicked(e, s);
        
                    app.AnnotationLines(e) = p;
                end
            end

        end


        function startNewEvent(app)

            if ~app.IsAddMode
                return
            end

            obj = hittest(app.UIFigure);
            ax  = ancestor(obj,'matlab.graphics.axis.Axes');
        
            % Not a signal axis → ignore
            if isempty(ax) || ~ismember(ax, app.SignalAxes)
                return
            end

            % Store active axis like edit mode does
            app.ActiveAddAxes = ax;
            app.ActiveAddSignalIndex = find(app.SignalAxes == ax);

            cp = ax.CurrentPoint;
            x = cp(1,1);
        
            fs = app.SamplingFrequency;
            winStartSec = (app.CurrentStartSample - 1) / fs;
        
            app.NewEventStartSec = x + winStartSec;
            app.IsDrawingNewEvent = true;
        
            y = ylim(ax);
        
            app.TempNewEventPatch = patch(ax, ...
                [x x x x], ...
                [y(1) y(1) y(2) y(2)], ...
                'g','FaceAlpha',0.25,'EdgeColor','none');
        
            app.UIFigure.WindowButtonMotionFcn = @(~,~)app.updateNewEventPatch();
        end


        function updateNewEventPatch(app)

            if ~app.IsDrawingNewEvent || ...
               isempty(app.TempNewEventPatch) || ...
               ~isgraphics(app.TempNewEventPatch)
                return
            end
        
            ax = app.ActiveAddAxes;
            cp = ax.CurrentPoint;
            xNow = cp(1,1);
        
            fs = app.SamplingFrequency;
            winStartSec = (app.CurrentStartSample - 1) / fs;
        
            xStart = app.NewEventStartSec - winStartSec;
        
            x1 = min(xStart, xNow);
            x2 = max(xStart, xNow);
        
            y = ylim(ax);
        
            app.TempNewEventPatch.XData = [x1 x2 x2 x1];
            app.TempNewEventPatch.YData = [y(1) y(1) y(2) y(2)];
        end


        function finishNewEvent(app)
    
            if ~app.IsDrawingNewEvent
                return
            end
        
            app.UIFigure.WindowButtonMotionFcn = [];
            app.UIFigure.WindowButtonUpFcn     = [];
        
            if isgraphics(app.TempNewEventPatch)
                delete(app.TempNewEventPatch)
            end
        
            ax = app.ActiveAddAxes;
            cp = ax.CurrentPoint;
            xEnd = cp(1,1);
        
            fs = app.SamplingFrequency;
            winStartSec = (app.CurrentStartSample - 1) / fs;
        
            startSec = app.NewEventStartSec;
            endSec   = xEnd + winStartSec;
        
            if endSec < startSec
                [startSec,endSec] = deal(endSec,startSec);
            end
        
            dur = endSec - startSec;
            if (dur < 0.5 && strcmp(app.NewEventType, 'Spindle')) || (dur < 0.5 && strcmp(app.NewEventType, 'KComplex'))
                uialert(app.UIFigure, ...
                    'Added event is too short. Event of this type should be atleast 0.5 s.', ...
                    'Event too short');
                app.cancelAddMode();
                return
            end

            if dur < 2 && strcmp(app.NewEventType, 'Delta Wave') 
                uialert(app.UIFigure, ...
                    'Added event is too short. Event of this type should be atleast 2 s.', ...
                    'Event too short');
                app.cancelAddMode();
                return
            end

            % OVERLAP CHECK (same type, same channel optional)
            % -------------------------------------------------
            
            newType = lower(app.NewEventType);
            newChannel = app.SignalNames{app.ActiveAddSignalIndex}; 
            
            if ~isempty(app.Events)
            
                % Convert struct array to vectors for vectorized comparison
                starts = [app.Events.start_sec];
                ends   = [app.Events.end_sec];
                types  = string({app.Events.type});
                channels = string({app.Events.channels});
            
                % Match same type (and channel if required)
                % sameTypeMask = types == newType;
            
                % Events to test against
                testMask = (types == newType) & (channels == newChannel) & ~[app.Events.deleted];
            
                if any(testMask)
            
                    oldStarts = starts(testMask);
                    oldEnds   = ends(testMask);
            
                    % Interval overlap test (vectorized)
                    overlapMask = (startSec < oldEnds) & (endSec > oldStarts);
            
                    if any(overlapMask)
            
                        selection = uiconfirm(app.UIFigure, ...
                        'This event overlaps another of the same type. Add anyway?', ...
                        'Overlap Warning', ...
                        'Options',{'Cancel','Add Anyway'}, ...
                        'DefaultOption',1,'CancelOption',1);

                        if strcmp(selection,'Cancel')
                            app.cancelAddMode();
                            return
                        end
                    end
                end
            end

            d = uiprogressdlg(app.UIFigure, ...
                'Title','Adding Event', ...
                'Message','Preparing event...', ...
                'Indeterminate','off', ...
                'Value',0);
            drawnow
            
            % --- Create new event struct ---
            fields = fieldnames(app.Events);

            newEvt = cell2struct(cell(size(fields)), fields, 1);

            indices = round(startSec*app.SamplingFrequency:endSec*app.SamplingFrequency);
            signal = app.Signals.(app.SignalNames{app.ActiveAddSignalIndex});
            deltaSignal = app.SignalNames{app.ActiveAddSignalIndex} + "_delta";
            sigmaSignal = app.SignalNames{app.ActiveAddSignalIndex} + "_sigma";
            signal_delta = app.SignalsExtra.(deltaSignal);
            signal_sigma = app.SignalsExtra.(sigmaSignal);
            

            if strcmp(app.NewEventType, 'Spindle')
                segment = signal_sigma(indices);
        
                sigma_power = zeros(size(indices));
    
                padL = floor((0.1/2)*app.SamplingFrequency); % Zero padding length for the window
                WinS = floor(0.05*app.SamplingFrequency);
                WinL = floor(0.1*app.SamplingFrequency);
    
            
                for j = 1:WinS:length(segment) - WinL + 1
                    % Define window indices
                    Wstart = max(1, j - padL);
                    Wend   = min(length(segment), j + WinL - 1);
                    
                    % Extract and pad window symmetrically if needed
                    window = segment(Wstart:Wend);
                    if length(window) < WinL
                        window = padarray(window,padL,'pre'); % pad at the end if short
                    end
                    
                    % Compute power in the window
                    pw = sum(window.^2) / WinL;
                    
                    % Assign the same value to all samples within the window
                    sigma_power(j:j+WinL-1) = max(log10(pw));
                    max_power = max(sigma_power);
                end

                segment = signal(indices);
                [pxx, f] = pwelch(segment, [], [], [], app.SamplingFrequency);
                
                % Limit to spindle range (9–16 Hz)
                valid = f >= 9 & f <= 16;
                f_valid = f(valid);
                pxx_valid = pxx(valid);
                
                % Identify peak frequency
                [~, idx_max] = max(pxx_valid);
                f_peak = f_valid(idx_max);

                newEvt.spindle_power = max_power;
                newEvt.max_fft = f_peak;
                amplitude = max(max(segment)-min(segment));
            end

            if strcmp(app.NewEventType, 'KComplex')
                segment = signal_delta(indices);

                [pks_min_neg, locs_min] = findpeaks(-segment, 'MinPeakProminence', 20);
                pks_min = -pks_min_neg;
                
                if isempty(locs_min)
                    [~, locs_min] = min(segment);   % global min
                    pks_min = segment(locs_min);
                end
                
                [~, idx_deep] = min(pks_min);
                min_loc = locs_min(idx_deep);
                [~, locs_max] = findpeaks(segment, 'MinPeakProminence', 10);
                W = round(0.5 * length(segment));

                % --- Left side ---
                idxL = locs_max < min_loc & locs_max >= min_loc - W;
                if any(idxL)
                    % nearest maximum
                    [~, rel] = min(abs(locs_max(idxL) - min_loc));
                    left_loc = locs_max(idxL);
                    left_loc = left_loc(rel);
                else
                    % fallback: take left window boundary
                    left_loc = max(1, min_loc - W);
                end
                
                % --- Right side ---
                idxR = locs_max > min_loc & locs_max <= min_loc + W;
                if any(idxR)
                    % nearest maximum
                    [~, rel] = min(abs(locs_max(idxR) - min_loc));
                    right_loc = locs_max(idxR);
                    right_loc = right_loc(rel);
                else
                    % fallback: take right window boundary
                    right_loc = min(length(segment), min_loc + W);
                end

                slope_width = (right_loc - left_loc)/app.SamplingFrequency;
        
                amplitude = max(max(segment)-min(segment));
                newEvt.slope_width_seconds = slope_width;
            end
            
            if strcmp(app.NewEventType, 'Delta Wave')
                segment = signal_delta(indices);
                delta_power = zeros(size(indices));
    
                padL = floor((0.1/2)*app.SamplingFrequency); % Zero padding length for the window
                WinS = floor(0.05*app.SamplingFrequency);
                WinL = floor(0.1*app.SamplingFrequency);
    
            
                for j = 1:WinS:length(segment) - WinL + 1
                    % Define window indices
                    Wstart = max(1, j - padL);
                    Wend   = min(length(segment), j + WinL - 1);
                    
                    % Extract and pad window symmetrically if needed
                    window = segment(Wstart:Wend);
                    if length(window) < WinL
                        window = padarray(window,padL,'pre'); % pad at the end if short
                    end
                    
                    % Compute power in the window
                    pw = sum(window.^2) / WinL;
                    
                    % Assign the same value to all samples within the window
                    delta_power(j:j+WinL-1) = max(log10(pw));
                    max_power = max(delta_power);
                end
                segment = signal(indices);
                [pxx, f] = pwelch(segment, [], [], [], app.SamplingFrequency);
                
                % Limit to delta range (0.1 - 4.5 Hz)
                valid = f >= 0.1 & f <= 4.5;
                f_valid = f(valid);
                pxx_valid = pxx(valid);
                
                % Identify peak frequency
                [~, idx_max] = max(pxx_valid);
                f_peak = f_valid(idx_max);

                newEvt.delta_power = max_power;
                newEvt.max_fft = f_peak;                     
            end
            
        
            newEvt.File_ID = app.Events(1).File_ID;
            newEvt.start_sec = startSec;
            newEvt.end_sec   = endSec;
            newEvt.duration  = dur;
            if strcmp(app.NewEventType, 'Delta Wave')
                newEvt.type = "Delta_waves";
            elseif strcmp(app.NewEventType, 'KComplex')
                newEvt.type = "Kcomplex";
            elseif strcmp(app.NewEventType, 'Spindle')
                newEvt.type = "spindle";
            elseif strcmp(app.NewEventType, 'A Phase')
                newEvt.type = "A_phase";
            end
            newEvt.channels  = app.SignalNames{app.ActiveAddSignalIndex};
            newEvt.sampling_frequency = app.SamplingFrequency;
            newEvt.indices   = indices;
            if strcmp(app.NewEventType, 'Spindle') || strcmp(app.NewEventType, 'KComplex')
                newEvt.amplitude = amplitude;
            end
            newEvt.add_name  = "UNSURE";
            newEvt.deleted   = false;
            newEvt.edited    = true;
            
            for k = 1:length(fields)
                val = newEvt.(fields{k});
            
                % Replace only if empty and numeric
                if isempty(val) && isnumeric(val)
                    newEvt.(fields{k}) = NaN;
                end
            end
        
            app.Events(end+1) = newEvt;
            
            d.Message = 'Rebuilding annotation graphics...';
            d.Value = 0.35;
            drawnow

            app.initializeAnnotationPatches();
        
            % --- SORT EVENTS ---
            [~,idx] = sort([app.Events.start_sec]);
            app.Events = app.Events(idx);
            
            d.Message = 'Resizing annotation structures...';
            d.Value = 0.7;
            drawnow

            % Find index of new event after sorting
            newIdx = find(idx == numel(app.Events));
            
            % Create patch for new event only
            evt = app.Events(newIdx);
            s = app.ActiveAddSignalIndex;   % channel index
            ax = app.SignalAxes(s);
            y  = ylim(ax);
            
            p = patch(ax, ...
                [evt.start_sec - winStartSec, evt.end_sec - winStartSec, ...
                 evt.end_sec - winStartSec, evt.start_sec - winStartSec], ...
                [y(1) y(1) y(2) y(2)], ...
                'r', 'FaceAlpha',0.15, 'EdgeColor','none', 'Visible','on');
            
            p.ButtonDownFcn = @(~,~) app.onAnnotationClicked(newIdx, s);
            
            % Append patch to end of vector
            app.AnnotationLines(end+1) = p;
        
            app.CurrentEventIndex = newIdx;
        
            app.cancelAddMode();
            app.updateSignals();
            app.updateEventInfoPanel();
            app.updateValidEvents();
            d.Value = 1;
            pause(0.05)   % allows bar to reach end visually
            close(d)
        end


        function cancelAddMode(app)
            app.IsAddMode = false;
            app.IsDrawingNewEvent = false;

            app.ActiveAddAxes = matlab.graphics.axis.Axes.empty;
            app.ActiveAddSignalIndex = NaN;
    
            % Reset cursor and callbacks
            app.UIFigure.Pointer = 'arrow';
            app.UIFigure.WindowButtonDownFcn = [];
            app.UIFigure.WindowButtonMotionFcn = [];
            app.UIFigure.WindowButtonUpFcn = [];
        end


        function updateResizePatch(app)

            if ~app.IsResizing || isempty(app.TempResizePatch) || ~isgraphics(app.TempResizePatch)
                return
            end
        
            ax = app.ActiveEditAxes;
            if isempty(ax) || ~isgraphics(ax)
                return
            end
            obj = hittest(app.UIFigure);
            if isempty(ancestor(obj,'matlab.graphics.axis.Axes'))
                return
            end
            cp = ax.CurrentPoint;
            if isempty(cp)
                return
            end
            x = cp(1,1);
        
            fs = app.SamplingFrequency;
            winStartSec = (app.CurrentStartSample - 1) / fs;
            t = x + winStartSec;
        
            e = app.Events(app.CurrentEventIndex);
            switch app.ResizeEdge
                case 'left'
                    newStart = min(t, e.end_sec - 0.01);
                    x1 = newStart - winStartSec;
                    x2 = e.end_sec - winStartSec;
                case 'right'
                    newEnd = max(t, e.start_sec + 0.01);
                    x1 = e.start_sec - winStartSec;
                    x2 = newEnd - winStartSec;
            end
        
            y = ylim(ax);
            app.TempResizePatch.XData = [x1 x2 x2 x1];
            app.TempResizePatch.YData = [y(1) y(1) y(2) y(2)];

            % --- Paired event (same x coordinates!) ---
            if ~isempty(app.TempPairedPatch) && isgraphics(app.TempPairedPatch)
                pairedAx = app.TempPairedPatch.Parent;  % already created in startResizeEvent
                yPaired = ylim(pairedAx);
                app.TempPairedPatch.XData = [x1 x2 x2 x1];
                app.TempPairedPatch.YData = [yPaired(1) yPaired(1) yPaired(2) yPaired(2)];
            end
        end

        
        function finishResizeEvent(app)

            if ~app.IsResizing
                return
            end
        
            ax = app.ActiveEditAxes;
            cp = ax.CurrentPoint;
            x = cp(1,1);
        
            fs = app.SamplingFrequency;
            winStartSec = (app.CurrentStartSample - 1) / fs;
            t = x + winStartSec;
        
            eIdx = app.CurrentEventIndex;
            e = app.Events(eIdx);

            % --- Compute new boundaries first ---
            newStart = e.start_sec;
            newEnd   = e.end_sec;
        
            switch app.ResizeEdge
                case 'left'
                    newStart = min(t, e.end_sec - 0.01);
                case 'right'
                    newEnd = max(t, e.start_sec + 0.01);
            end
        
            newDuration = newEnd - newStart;
            newIndices  = round(newStart*fs) : round(newEnd*fs);

            % --- Update current event ---
            app.Events(eIdx).start_sec = newStart;
            app.Events(eIdx).end_sec   = newEnd;
            app.Events(eIdx).duration  = newDuration;
            app.Events(eIdx).indices   = newIndices;
            app.Events(eIdx).edited    = true;

            % --- Update paired event if it exists ---
            if ~isempty(app.PairedEventIndex)
                pairedIdx = app.PairedEventIndex;
                app.Events(pairedIdx).start_sec = newStart;
                app.Events(pairedIdx).end_sec   = newEnd;
                app.Events(pairedIdx).duration  = newDuration;
                app.Events(pairedIdx).indices   = newIndices;
                app.Events(pairedIdx).edited    = true;
            end

            % Delete temp patch
            if isgraphics(app.TempResizePatch)
                delete(app.TempResizePatch);
            end

            if isgraphics(app.TempPairedPatch)
                delete(app.TempPairedPatch);
                app.TempPairedPatch = [];
            end
        
            % Update all annotations now
            app.drawAnnotations();
            app.highlightCurrentEvent();
            app.updateEventInfoPanel();
            app.updateFFT();
        
            % Reset state
            app.IsResizing = false;
            app.ResizeEdge = '';
            app.UIFigure.WindowButtonMotionFcn = [];
            app.UIFigure.WindowButtonUpFcn = [];
        end


        function updateFFT(app)

            curEvent = app.Events(app.CurrentEventIndex);
            eventIndices = curEvent.indices;

            if ~app.UIAxes.Visible
                cla(app.UIAxes);
                return
            end

            if strcmp(curEvent.type, 'A_phase')
                return
            end
            
            if strcmp(curEvent.type, 'Delta_waves')
                exp = 2 * app.SamplingFrequency;
            elseif strcmp(curEvent.type, 'spindle') || strcmp(curEvent.type, 'Kcomplex')
                exp = 0.5 * app.SamplingFrequency;
            end

            fs = app.SamplingFrequency;
            fieldNam = fieldnames(app.Signals);
            activeName = fieldNam{1};
            activeNameDelta = activeName + "_delta";


            if strcmp(curEvent.type, 'Delta_waves')
                sigPart = app.SignalsExtra.(activeNameDelta)(eventIndices(1) - exp : eventIndices(end) + exp);
            elseif strcmp(curEvent.type, 'spindle') || strcmp(curEvent.type, 'Kcomplex')
                sigPart = app.Signals.(activeName)(eventIndices(1) - exp : eventIndices(end) + exp);
            end
            
            sigPart = sigPart - mean(sigPart);
            N = length(sigPart);
            Y = fft(sigPart .* hann(N));
            P = abs(Y/N).^2;
            f = (0:N-1)*(fs/N);
            
            % Limit to spindle range (9–16 Hz)
            if strcmp(curEvent.type, 'Delta_waves') || strcmp(curEvent.type, 'Kcomplex')
                valid = f >= 0.1 & f <=5;
            elseif strcmp(curEvent.type, 'spindle')
                valid = f >= 9 & f <=16;
            end
            f_valid = f(valid);
            pxx_valid = P(valid);
            
            % Identify peak frequency
            [~, idx_max] = max(pxx_valid);
            f_peak = f_valid(idx_max);

            
            plot(app.UIAxes, f_valid, 10*log10(pxx_valid));
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, f_peak, max(10*log10(pxx_valid)), 'Color', 'r','Marker','o');
            xlim(app.UIAxes, [min(f_valid) max(f_valid)]);
            hold(app.UIAxes, 'off');
        end
        
              
        function drawManualYTicks(app, ax)

            % Remove old manual labels
            delete(findall(ax,'Tag','ManualYTick'));
        
            yticks = ax.YTick;
        
            % Position just inside left border
            x = ax.XLim(1) - 0.1;
        
            for k = 1:length(yticks)
        
                text(ax, x, yticks(k), num2str(yticks(k)), ...
                    'HorizontalAlignment','right', ...
                    'VerticalAlignment','middle', ...
                    'Units','data', ...
                    'Tag','ManualYTick', ...
                    'Clipping','off');
            end
        end
        
        function updateValidEvents(app)

            signalNames = fieldnames(app.Signals);
            nEvents = numel(app.Events);
        
            valid = false(nEvents,1);
        
            for k = 1:nEvents
        
                evChannel = app.Events(k).channels;
                % Normalize evChannel to a cell array of strings
                if iscell(evChannel)
                    evChList = evChannel;  % already a cell array
                elseif ischar(evChannel) || isstring(evChannel)
                    % If it's a comma-separated string, split it
                    evChList = strsplit(evChannel, ',');
                else
                    error('Unexpected channels format for event %d', k);
                end
        
                if any(ismember(evChList, signalNames))
                    valid(k) = true;
                end
            end
        
            app.ValidEventIndices = find(valid);

            % --- Set CurrentValidEventPointer ---
            if isempty(app.CurrentEventIndex) || app.CurrentEventIndex == 0
                % Startup: no current event yet
                app.CurrentValidEventPointer = 0;
            else
                % Runtime: current event is valid, find its pointer
                app.CurrentValidEventPointer = find(app.ValidEventIndices == app.CurrentEventIndex, 1);
            end
        
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, signals, annotations, fs, subjectID)
            % Store incoming data
            app.NumberOfCapChannels = 0;
            sigNames = fieldnames(signals);
            app.Signals = struct();
            app.SignalsExtra = struct();

            for i = 1:length(sigNames)

                name = sigNames{i};
            
                if contains(name, "_delta") || contains(name, "_sigma")
                    app.SignalsExtra.(name) = signals.(name);
                else
                    app.Signals.(name) = signals.(name);
                end
            end

            app.Annotations = annotations;
            if isfield(app.Annotations{1}, 'event_name') && strcmp(annotations{1}(1).event_name, 'A_phase')
                ch = {app.Annotations{1}.channels};
                numChannels = numel(ch);
                if numChannels > 1
                    app.NumberOfCapChannels = numChannels;
                end
            end
            app.SamplingFrequency = fs;
            app.SignalNames = fieldnames(app.Signals);

            app.ChannelMap = containers.Map(app.SignalNames, ...
                                 1:numel(app.SignalNames));

            app.createSignalAxes();

            app.UIFigure.Icon = 'ODIN_logo.png';
            app.UIFigure.Name = sprintf('ODIN EDITOR - Subject %s', subjectID);

            app.CurrentStartSample = 1;
            app.WindowLength = 30 * fs;
            app.HypnogramEpochLength = 30 * fs;
            
            % Enable arrow key navigation
            app.UIFigure.KeyPressFcn = @(src,event) app.keyPressNavigation(event);

            % Normalize annotations
            app.Events = app.flattenAnnotations(annotations);
            app.CurrentEventIndex = 0;

            types = {app.Events.type};
            types = cellfun(@char, types, 'UniformOutput', false);
            eventTypes = unique(types);

            app.EventTypes = eventTypes;

            app.updateValidEvents();

            app.drawAnnotations();

            app.createHypnogramAxes();
            app.updateHypnogramIndicator();
        end

        % Value changed function: EpochLengthDropDown
        function EpochLengthChanged(app, event)
            if ~isempty(app.HelpLines) && strcmp(app.HelpLines{1}.Visible, 'on')
                app.HelpLineButtonPushed();
            end

            value = app.EpochLengthDropDown.Value;

            % Extract numeric seconds
            seconds = sscanf(value, '%d');
            if isempty(seconds)
                return
            end

            fs = app.SamplingFrequency;

            % Update epoch length (IN SAMPLES)
            app.WindowLength = round(seconds * fs);

            % Clamp current start sample
            sigNames = fieldnames(app.Signals);
            sigLen = length(app.Signals.(sigNames{1}));
            maxStart = max(1, sigLen - app.WindowLength + 1);
            app.CurrentStartSample = min(app.CurrentStartSample, maxStart);

            % Force redraw at same position
            app.keyPressNavigation(struct('Key',''));  % reuse logic

            
        end

        % Button pushed function: NextEventButton
        function NextEventButtonPushed(app, event)
            if isempty(app.ValidEventIndices)
                return
            end

            if app.NumberOfCapChannels > 1 && numel(fieldnames(app.Signals)) == 2
                % Move pointer forward
                app.CurrentValidEventPointer = ...
                    min(app.CurrentValidEventPointer + 2, ...
                        numel(app.ValidEventIndices));
            else
                % Move pointer forward
                app.CurrentValidEventPointer = ...
                    min(app.CurrentValidEventPointer + 1, ...
                        numel(app.ValidEventIndices));
            end

            idx = app.ValidEventIndices(app.CurrentValidEventPointer);
            % Skip deleted events
            while app.Events(idx).deleted
                app.CurrentValidEventPointer = ...
                    min(app.CurrentValidEventPointer + 1, ...
                        numel(app.ValidEventIndices));
        
                idx = app.ValidEventIndices(app.CurrentValidEventPointer);
        
                if app.CurrentValidEventPointer == numel(app.ValidEventIndices)
                    break
                end
            end

            app.selectEvent(idx);
            app.updateFFT();
            
        end

        % Button pushed function: PreviousEventButton
        function PreviousEventButtonPushed(app, event)
            if isempty(app.ValidEventIndices)
                return
            end

            if app.NumberOfCapChannels > 1
                app.CurrentValidEventPointer = ...
                    max(app.CurrentValidEventPointer - 2, 1);
            else
                app.CurrentValidEventPointer = ...
                    max(app.CurrentValidEventPointer - 1, 1);
            end
        
            idx = app.ValidEventIndices(app.CurrentValidEventPointer);
        
            while app.Events(idx).deleted
                app.CurrentValidEventPointer = ...
                    max(app.CurrentValidEventPointer - 1, 1);
        
                idx = app.ValidEventIndices(app.CurrentValidEventPointer);
        
                if app.CurrentValidEventPointer == 1
                    break
                end
            end
        
            app.selectEvent(idx);
            app.updateFFT();
 
        end

        % Button pushed function: DeleteEventButton
        function DeleteEventButtonPushed(app, event)

            if app.IsEditMode
                uialert(app.UIFigure, ...
                    'Editing mode is active. Please, disable the editing mode before removing events.', ...
                    'Editing mode enabled');
                return;
            end

            if isempty(app.CurrentEventIndex) || ...
               app.CurrentEventIndex < 1 || ...
               app.CurrentEventIndex > numel(app.Events)
                return
            end

            app.selectEvent(app.CurrentEventIndex);


            % --- Confirmation dialog ---
            selection = uiconfirm( ...
                app.UIFigure, ...
                'Are you sure you want to delete this event?', ...
                'Confirm Delete', ...
                'Options', {'Delete','Cancel'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 2, ...
                'Icon', 'warning');
        
            % Abort if user cancels
            if ~strcmp(selection,'Delete')
                return
            end

            idx = app.CurrentEventIndex;
            app.Events(idx).deleted = true;

            % If multiple cap channels, delete paired event
            if app.NumberOfCapChannels > 1
            
                % Check previous event
                if idx > 1
                    if app.Events(idx-1).start_sec == app.Events(idx).start_sec
                        app.Events(idx-1).deleted = true;
                        return
                    end
                end
            
                % Check next event
                if idx < numel(app.Events)
                    if app.Events(idx+1).start_sec == app.Events(idx).start_sec
                        app.Events(idx+1).deleted = true;
                    end
                end
            end
            
            % redraw updates 
            app.drawAnnotations();
            app.highlightCurrentEvent();
        end

        % Button pushed function: EditEventButton
        function EditEventButtonPushed(app, event)

            app.UIFigure.WindowButtonMotionFcn = @(~,~)app.updateCursor();

            if ~app.IsEditMode
                % ENTER edit mode
                app.IsEditMode = true;
                app.EditingModeEnabledLabel.Visible = "on";
                app.Label_7.Visible = "off";

                % ---------------- BACKUP STATE ----------------
                app.BackupEvents = app.Events;
                app.BackupCurrentEventIndex = app.CurrentEventIndex;
                % ------------------------------------------------
        
                % Show alert only once
                if ~app.HasShownEditAlert
                    uialert(app.UIFigure, ...
                        sprintf(['Drag the left or right edge of the highlighted event to resize it.\n' ...
                                 'Press Edit Event again to finish.\n' ...
                                 'Press ESC to cancel editing.']), ...
                        'Edit Mode Enabled', ...
                        'Icon','info');
                    app.HasShownEditAlert = true;  % set flag so it won't show again
                end
        
            else
                % EXIT edit mode
                app.IsEditMode = false;
                app.EditingModeEnabledLabel.Visible = "off";
                app.Label_7.Visible = "on";
        
                % Mark edited
                app.Events(app.CurrentEventIndex).edited = true;
        
                app.updateEventInfoPanel();
                app.UIFigure.WindowButtonMotionFcn = [];
                app.UIFigure.Pointer = 'arrow';
            end

            if app.IsEditMode
                app.EditEventButton.Text = {'Finish'; 'Edit'};
            else
                app.EditEventButton.Text = {'Edit'; 'Event'};
            end
        end

        % Button pushed function: LoadHypnogramButton
        function LoadHypnogramButtonPushed(app, event)
            app.openHypnogramLoadDialog();
        end

        % Button pushed function: SaveExitEditorButton
        function SaveExitButtonPushed(app, event)

            % ---------------------------------------------------------
            % 0. Confirm save & exit
            % ---------------------------------------------------------
            selection = uiconfirm( ...
                app.UIFigure, ...
                'Are you sure you want to save all edited annotations and exit the editor?', ...
                'Confirm Save & Exit', ...
                'Options', {'Save & Exit','Cancel'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 2, ...
                'Icon','question');
        
            if ~strcmp(selection,'Save & Exit')
                return
            end

            % ---------------------------------------------------------
            % 1. Collect valid (non-deleted) events
            % ---------------------------------------------------------
            allEvents = app.Events;
            keepMask  = ~[allEvents.deleted];
        
            if ~any(keepMask)
                uialert(app.UIFigure, ...
                    'No events remain to save.', ...
                    'Save aborted', ...
                    'Icon','warning');
                return
            end
        
            validEvents = allEvents(keepMask);
            ID = char(validEvents(1).File_ID);
        
            % ---------------------------------------------------------
            % 2. Group by event type
            % ---------------------------------------------------------
            eventTypes = string({validEvents.type});
            uniqueTypes = unique(eventTypes);
        
            % ---------------------------------------------------------
            % 3. Ask user for save base name
            % ---------------------------------------------------------
            path = uigetdir(pwd, 'Select folder to save edited annotations');

            if isequal(path,0)
                return   % user cancelled
            end
            
            baseName = 'Edited';
        
            % ---------------------------------------------------------
            % 4. Save one MAT per event type
            % ---------------------------------------------------------
            for t = 1:numel(uniqueTypes)
        
                thisType = uniqueTypes(t);
                idx = eventTypes == thisType;
                evts = validEvents(idx);
        
                % rebuild clean structs
                events = arrayfun( ...
                    @(e) app.rebuildEventStruct(e), ...
                    evts, ...
                    'UniformOutput', false);
                events = events(:);

                channels_list = cellfun(@(x) x.channels, events, 'UniformOutput', false);
                unique_channels = unique(channels_list);  % list of unique channel names
                num_channels = length(unique_channels);

                if strcmp(thisType, 'Kcomplex')
                    dataTableKcomplex = cell(2, num_channels);

                    % Loop through each unique channel
                    for i = 1:num_channels
                        ch = unique_channels{i};
                        dataTableKcomplex{1, i} = ch;  % first row is channel name
                        
                        % Get indices of events for this channel
                        idx = strcmp(channels_list, ch);
                        channel_events = events(idx);
                        
                        % Sort the structs by start_sec
                        [~, sortIdx] = sort(cellfun(@(x) x.start_sec, channel_events));
                        dataTableKcomplex{2, i} = channel_events(sortIdx);
                    end
                    
                end

                if strcmp(thisType, 'Delta_waves')
                    dataTableSWS = cell(2, num_channels);

                    % Loop through each unique channel
                    for i = 1:num_channels
                        ch = unique_channels{i};
                        dataTableSWS{1, i} = ch;  % first row is channel name
                        
                        % Get indices of events for this channel
                        idx = strcmp(channels_list, ch);
                        channel_events = events(idx);
                        
                        % Sort the structs by start_sec
                        [~, sortIdx] = sort(cellfun(@(x) x.start_sec, channel_events));
                        dataTableSWS{2, i} = channel_events(sortIdx);
                    end
                    
                end

                if strcmp(thisType, 'spindle')
                    dataTableSpindle = cell(2, num_channels);

                    % Loop through each unique channel
                    for i = 1:num_channels
                        ch = unique_channels{i};
                        dataTableSpindle{1, i} = ch;  % first row is channel name
                        
                        % Get indices of events for this channel
                        idx = strcmp(channels_list, ch);
                        channel_events = events(idx);
                        
                        % Sort the structs by start_sec
                        [~, sortIdx] = sort(cellfun(@(x) x.start_sec, channel_events));
                        dataTableSpindle{2, i} = channel_events(sortIdx);
                    end
                    
                end


                if strcmp(thisType, 'A_phase')
                    % Extract start times
                    startTimes = [allEvents.start_sec];
                    
                    % Unique groups
                    [uniqueTimes, ~, groupIdx] = unique(startTimes);
                    
                    % Preallocate cell
                    groupedEvents = cell(length(uniqueTimes), 1);
                    
                    for k = 1:length(uniqueTimes)
                        idx = find(groupIdx == k);
                    
                        % Store all matching events as struct array
                        groupedEvents{k} = reshape(allEvents(idx), [], 1);
                    end

                    A_phases = groupedEvents;

                    A_clean = cellfun(@(x) x(1), A_phases);

                    [~, idx] = sort([A_clean.start_sec]);
                    A_clean = A_clean(idx);
                    A_phases = A_phases(idx); 

                    % Merge those A phases that are under 2 s apart

                    difference = 2; % seconds
                    j = 1;
                    
                    while j < numel(A_clean)
                    
                        gap = A_clean(j+1).start_sec - A_clean(j).end_sec;
                    
                        if gap <= difference
                    
                            % Merge
                            A_clean(j).end_sec = max(A_clean(j).end_sec, A_clean(j+1).end_sec);
                            A_clean(j).duration = A_clean(j).end_sec - A_clean(j).start_sec;

                            % Merge channel-wise
                            for ch = 1:numel(A_phases{j})
                            
                                % Update timing
                                A_phases{j}(ch).end_sec = ...
                                    max(A_phases{j}(ch).end_sec, A_phases{j+1}(ch).end_sec);
                            
                                A_phases{j}(ch).start_sec = ...
                                    min(A_phases{j}(ch).start_sec, A_phases{j+1}(ch).start_sec);
                            
                                % Update duration
                                A_phases{j}(ch).duration = ...
                                    A_phases{j}(ch).end_sec - A_phases{j}(ch).start_sec;
                            
                                % Merge indices properly
                                A_phases{j}(ch).indices = ...
                                    (min([A_phases{j}(ch).indices; A_phases{j+1}(ch).indices]) : ...
                                     max([A_phases{j}(ch).indices; A_phases{j+1}(ch).indices]))';
                            
                            end
          
                            A_clean(j+1) = [];
                            A_phases(j+1) = [];
                    
                        else
                            j = j + 1;
                        end
                    end

                    % Form CAP cycles
                    cycle_limit = 60;
                    cycles = {};
                    currentCycle = {};
                    
                    for i = 1:numel(A_clean)-1
                    
                        currentA = A_clean(i);
                        nextA = A_clean(i+1);
                    
                        gap = nextA.start_sec - currentA.end_sec;
                    
                        if gap > 0 && gap <= cycle_limit
                    
                            % A-phase
                            currentCycle{end+1,1} = currentA;
                            currentCycle{end,2} = 'A';
                    
                            % B-phase
                            B.start_sec = currentA.end_sec;
                            B.end_sec = nextA.start_sec;
                            B.duration = gap;
                    
                            currentCycle{end+1,1} = B;
                            currentCycle{end,2} = 'B';
                    
                        else
                            currentCycle{end+1,1} = currentA;
                            currentCycle{end,2} = 'A';
                    
                            cycles{end+1} = currentCycle;
                            currentCycle = {};
                        end
                    end

                    % Remove all those cells from cycles that are too short to be a cycle.
                    % In other words, remove the isolated A-phases that are non-CAP.
                    for i = length(cycles):-1:1
                        current_cycle = cycles{i};
                
                        if size(current_cycle,1) == 1
                            % Remove the element from the cell array
                            cycles(i) = [];
                        end
                    end
                
                    cycles = cycles';

                    % now the remaining cycles represent the CAP sequences. Create a vaiable sequences to
                    % distinguish the individual cycles and sequences.
                
                    sequences = cycles;
                
                    % Pick up the cycles from sequences for saving
                    cycles = {};
                
                    for i = length(sequences):-1:1
                        current_sequence = sequences{i};
                        
                        for k = 1:length(current_sequence)/2
                            A = (k-1)*2 + 1;
                            B = A + 1;
                            cycles{end+1} = current_sequence(A:B,:); 
                        end
                    end
                    
                    cycles = cycles';

                    % Remove those sequences that do not meet the criteria for CAP sequence

                    for i = length(sequences):-1:1
                        current_cycle = sequences{i};
                
                        if size(current_cycle,1) < 5
                            % Remove the element from the cell array
                            sequences(i) = [];
                        end
                    end
                
                
                    % Remove the last A-phase from each sequence that is counted as non-CAP
                
                    for i = length(sequences):-1:1
                        current_sequence = sequences{i};
                        current_sequence(end,:) = [];
                
                        sequences{i} = current_sequence;
                    end

                    cap = struct('A_phases', {A_phases}, 'Sequences', {sequences}, 'Cycles', {cycles});

                    
                end
        
        
                % safe filename
                if strcmp(thisType, 'A_phase')
                    typeName = 'CAP';
                elseif strcmp(thisType, 'spindle')
                    typeName = 'spindles';
                elseif strcmp(thisType, 'Kcomplex')
                    typeName = 'kcomplexes';
                elseif strcmp(thisType, 'Delta_waves')
                    typeName = 'delta_waves';
                end

                outFile  = fullfile(path, ...
                    sprintf('%s_%s_%s.mat',ID, typeName, baseName));

                if isfile(outFile)
                    choice = uiconfirm(app.UIFigure, ...
                        sprintf('File already exists:\n%s\nOverwrite?', outFile), ...
                        'Confirm overwrite', ...
                        'Options', {'Overwrite','Cancel'}, ...
                        'DefaultOption',2, ...
                        'CancelOption',2);
                    if ~strcmp(choice,'Overwrite')
                        continue
                    end
                end
                
                if strcmp(thisType, 'Kcomplex')
                    save(outFile, 'dataTableKcomplex')
                elseif strcmp(thisType, 'Delta_waves')
                    save(outFile, 'dataTableSWS')
                elseif strcmp(thisType, 'spindle')
                    save(outFile, 'dataTableSpindle')
                elseif strcmp(thisType, 'A_phase')
                    save(outFile, 'cap')
                end
            end
        
            % ---------------------------------------------------------
            % 5. Close editor
            % ---------------------------------------------------------
            delete(app)
        end

        % Button pushed function: GuideButton
        function GuideButtonPushed(app, event)
            app.showQuickStartDialog();
        end

        % Button pushed function: AddEventButton
        function AddEventButtonPushed(app, event)

            if isempty(app.NewEventType)
                uialert(app.UIFigure, ...
                    'Microstructure not selected. Use keyboard to select the correct microstructure for adding event.', ...
                    'Missing Event Type');
                return;
            end

            if app.IsAddMode
                uialert(app.UIFigure, ...
                    'Adding event is already active.', ...
                    'Adding mode active');
                return;
            end

            if app.IsEditMode
                uialert(app.UIFigure, ...
                    'Editing mode is active. Please, disable the editing mode before adding a new event.', ...
                    'Editing mode enabled');
                return;
            end    

            % app.NewEventType = string(types{indx});
            app.IsAddMode = true;
        
            app.UIFigure.Pointer = 'crosshair';
            app.UIFigure.WindowButtonDownFcn = @(~,~)app.startNewEvent();
            app.UIFigure.WindowButtonUpFcn   = @(~,~)app.finishNewEvent(); 
            
            if ~app.HasShownAddAlert
                uialert(app.UIFigure, ...
                    ['Click and drag on a signal to add the event.' newline ...
                    'Click empty space of the GUI and press esc to cancel.'], ...
                    'Add Mode Enabled', 'Icon','info');
                app.HasShownAddAlert = true;              
            end

        end

        % Button pushed function: HideShowFFTpanelButton
        function HideShowFFTPanelButtonPushed(app, event)
            if app.UIAxes.Visible
                cla(app.UIAxes);
                app.UIAxes.Visible = "off";
            elseif ~app.UIAxes.Visible
                app.UIAxes.Visible = "on";
                app.updateFFT();
            end
        end

        % Button pushed function: showhide75VlinesButton
        function HelpLineButtonPushed(app, event)
            % --- Create lines if they do not exist ---
            if isempty(app.HelpLines)
        
                app.HelpLines = cell(numel(app.SignalAxes), 2);
        
                for i = 1:numel(app.SignalAxes)
                    ax = app.SignalAxes(i);
        
                    % Create lines with temporary XData
                    l1 = line(ax, [0 1], [37.5 37.5], ...
                        'Color', [0 1 0 0.7], ...
                        'LineStyle', '--', ...
                        'LineWidth', 1, ...
                        'HandleVisibility','off');
        
                    l2 = line(ax, [0 1], [-37.5 -37.5], ...
                        'Color', [0 1 0 0.7], ...
                        'LineStyle', '--', ...
                        'LineWidth', 1, ...
                        'HandleVisibility','off');
        
                    app.HelpLines{i,1} = l1;
                    app.HelpLines{i,2} = l2;
                end
            end
        
            % --- Update X limits every time ---
            for i = 1:numel(app.SignalAxes)
        
                ax = app.SignalAxes(i);
                xlims = ax.XLim;
        
                for j = 1:2
                    if isgraphics(app.HelpLines{i,j})
                        app.HelpLines{i,j}.XData = xlims;
                    end
                end
            end
        
            % --- Toggle visibility ---
            for i = 1:numel(app.SignalAxes)
                for j = 1:2
                    if isgraphics(app.HelpLines{i,j})
                        if strcmp(app.HelpLines{i,j}.Visible,'on')
                            app.HelpLines{i,j}.Visible = 'off';
                        else
                            app.HelpLines{i,j}.Visible = 'on';
                        end
                    end
                end
            end
        end

        % Button pushed function: DeleteEventsfromWakeButton
        function DeleteEventsFromWakeButtonPushed(app, event)

            % Check if the events have already been deleted
            if app.HasDeleteWakeEvents
                uialert(app.UIFigure, ...
                    'Events in wake have already been deleted.', ...
                    'No Events');
                return;
            end
            
            % Check if the hypnogram has been loaded
            if isempty(app.Hypnogram)
                uialert(app.UIFigure, ...
                    'No hypnogram loaded for current subject.', ...
                    'Missing Hypnogram');
                return;
            end

            hypnogram = app.Hypnogram;
            allEvents = app.Events;

            % Upsample to 1-second resolution
            hypnogram_1s = repelem(hypnogram, 30);

            % Pick up the starting times of events
            startTimes = [allEvents.start_sec]';

            % Convert start times to nearest 1-second index
            eventIndices = round(startTimes);
            
            % Ensure indices stay within bounds of hypnogram_1s
            eventIndices(eventIndices < 1) = 1;
            eventIndices(eventIndices > length(hypnogram_1s)) = length(hypnogram_1s);
            
            % Logical mask for events starting during wake
            isWakeEvent = hypnogram_1s(eventIndices) == 0 | hypnogram_1s(eventIndices) == 4;
            
            % Indices into allEvents to delete
            wakeEventStructIdx = find(isWakeEvent);

            if isempty(wakeEventStructIdx)
                uialert(app.UIFigure, ...
                    'No events in wake found.', ...
                    'No Events Found');
                return;
            end

            % Delte events that start during wake
            for i = 1:length(wakeEventStructIdx)
                curIdx = wakeEventStructIdx(i);
                % Mark event as delted
                app.Events(curIdx).deleted = true;
            end

            app.HasDeleteWakeEvents = true;

            % redraw updates 
            app.drawAnnotations();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FFT')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Magnitude (dB)')
            zlabel(app.UIAxes, 'Z')
            app.SignalsPanel = uipanel(app.UIFigure);
            app.SignalsPanel.Title = 'Signals';
            app.HypnogramPanel = uipanel(app.UIFigure);
            app.HypnogramPanel.Title = 'Hypnogram';
            app.LoadHypnogramButton = uibutton(app.UIFigure, 'push');
            app.LoadHypnogramButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHypnogramButtonPushed, true);
            app.LoadHypnogramButton.Text = 'Load Hypnogram';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.GridLayout = uigridlayout(app.Panel);
            app.Label = uilabel(app.GridLayout);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.WLabel = uilabel(app.GridLayout);
            app.WLabel.Visible = 'off';
            app.WLabel.Text = 'W';
            app.N1Label = uilabel(app.GridLayout);
            app.N1Label.Visible = 'off';
            app.N1Label.Text = 'N1';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Visible = 'off';
            app.Label_5.Text = '';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = '';
            app.REMLabel = uilabel(app.GridLayout);
            app.REMLabel.Visible = 'off';
            app.REMLabel.Text = 'REM';
            app.N2Label = uilabel(app.GridLayout);
            app.N2Label.Visible = 'off';
            app.N2Label.Text = 'N2';
            app.N3Label = uilabel(app.GridLayout);
            app.N3Label.Visible = 'off';
            app.N3Label.Text = 'N3';
            app.Panel_2 = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.SelectedEventInfoTextAreaLabel = uilabel(app.GridLayout3);
            app.SelectedEventInfoTextAreaLabel.Text = 'Selected Event Info';
            app.SelectedEventInfoTextArea = uitextarea(app.GridLayout3);
            app.Panel_3 = uipanel(app.UIFigure);
            app.GridLayout4 = uigridlayout(app.Panel_3);
            app.PreviousEventButton = uibutton(app.GridLayout4, 'push');
            app.PreviousEventButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousEventButtonPushed, true);
            app.PreviousEventButton.Text = 'Previous Event';
            app.DeleteEventButton = uibutton(app.GridLayout4, 'push');
            app.DeleteEventButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteEventButtonPushed, true);
            app.DeleteEventButton.Text = {'Delete '; 'Event'};
            app.EditingModeEnabledLabel = uilabel(app.GridLayout4);
            app.EditingModeEnabledLabel.Visible = 'off';
            app.EditingModeEnabledLabel.Text = {'Editing Mode'; 'Enabled'};
            app.NextEventButton = uibutton(app.GridLayout4, 'push');
            app.NextEventButton.ButtonPushedFcn = createCallbackFcn(app, @NextEventButtonPushed, true);
            app.NextEventButton.Text = 'Next Event';
            app.EditEventButton = uibutton(app.GridLayout4, 'push');
            app.EditEventButton.ButtonPushedFcn = createCallbackFcn(app, @EditEventButtonPushed, true);
            app.EditEventButton.Text = {'Edit'; 'Event'};
            app.AddEventButton = uibutton(app.GridLayout4, 'push');
            app.AddEventButton.ButtonPushedFcn = createCallbackFcn(app, @AddEventButtonPushed, true);
            app.AddEventButton.Text = {'Add'; 'Event'};
            app.HideShowFFTpanelButton = uibutton(app.GridLayout4, 'push');
            app.HideShowFFTpanelButton.ButtonPushedFcn = createCallbackFcn(app, @HideShowFFTPanelButtonPushed, true);
            app.HideShowFFTpanelButton.Text = {'Hide/Show'; 'FFT panel'};
            app.Label_7 = uilabel(app.GridLayout4);
            app.Label_7.Text = '';
            app.DeleteEventsfromWakeButton = uibutton(app.GridLayout4, 'push');
            app.DeleteEventsfromWakeButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteEventsFromWakeButtonPushed, true);
            app.DeleteEventsfromWakeButton.Text = {'Delete Events '; 'from Wake'};
            app.Panel_4 = uipanel(app.UIFigure);
            app.GridLayout5 = uigridlayout(app.Panel_4);
            app.GuideButton = uibutton(app.GridLayout5, 'push');
            app.GuideButton.ButtonPushedFcn = createCallbackFcn(app, @GuideButtonPushed, true);
            app.GuideButton.Text = 'Guide';
            app.SaveExitEditorButton = uibutton(app.GridLayout5, 'push');
            app.SaveExitEditorButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitEditorButton.Text = {'Save'; '&'; 'Exit Editor'};
            app.EpochLengthDropDownLabel = uilabel(app.GridLayout5);
            app.EpochLengthDropDownLabel.Text = 'Epoch Length';
            app.EpochLengthDropDown = uidropdown(app.GridLayout5);
            app.EpochLengthDropDown.Items = {'10 s', '15 s', '30 s', '60 s'};
            app.EpochLengthDropDown.ValueChangedFcn = createCallbackFcn(app, @EpochLengthChanged, true);
            app.EpochLengthDropDown.Value = '30 s';
            app.Panel_5 = uipanel(app.UIFigure);
            app.GridLayout6 = uigridlayout(app.Panel_5);
            app.Label_6 = uilabel(app.GridLayout6);
            app.Label_6.Text = '';
            app.TextArea = uitextarea(app.GridLayout6);
            app.TextArea.Value = {'Use keyboard to select microstructure for adding event:'; 'S = spindle'; 'K = K-complex'; 'D = Delta waves'; ''; 'Spacebar = Add Event'; 'esc = clear selection'};
            app.showhide75VlinesButton = uibutton(app.GridLayout6, 'push');
            app.showhide75VlinesButton.ButtonPushedFcn = createCallbackFcn(app, @HelpLineButtonPushed, true);
            app.showhide75VlinesButton.Text = 'show/hide 75 μV lines';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OdinEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end